classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Triaxial_Experiment_Results     matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        experimentsAndRockDataTab       matlab.ui.container.Tab
        experimentOverviewPanel         matlab.ui.container.Panel
        experimentsOverviewTable        matlab.ui.control.Table
        SelectedExperimentLabel         matlab.ui.control.Label
        selectExperiment_Primary        matlab.ui.control.DropDown
        rockDataPanel                   matlab.ui.container.Panel
        RockDataTable                   matlab.ui.control.Table
        DataAndOutliersTab              matlab.ui.container.Tab
        DataAndWeightPanel              matlab.ui.container.Panel
        graphOverview                   matlab.ui.control.UIAxes
        graphWeight                     matlab.ui.control.UIAxes
        displayOutliersWeight           matlab.ui.control.CheckBox
        correctedGraphWeight            matlab.ui.control.CheckBox
        FluidFlowAndDeformationPanel    matlab.ui.container.Panel
        graphFluidFlow                  matlab.ui.control.UIAxes
        graphDeformation                matlab.ui.control.UIAxes
        timelogTab                      matlab.ui.container.Tab
        permeabilityResultsPanel        matlab.ui.container.Panel
        graph_TimelogTab                matlab.ui.control.UIAxes
        DisplayonrightaxisLabel         matlab.ui.control.Label
        y2Axis_TimelogTab               matlab.ui.control.DropDown
        ClearEventsButton               matlab.ui.control.Button
        timelogPanel                    matlab.ui.container.Panel
        timelogTableActual              matlab.ui.control.Table
        timelogTablePre                 matlab.ui.control.Table
        timelogTablePost                matlab.ui.control.Table
        TimestepminLabel_2              matlab.ui.control.Label
        selectTimestep_Primary          matlab.ui.control.DropDown
        DisplayonleftaxisLabel          matlab.ui.control.Label
        y1Axis_TimelogTab               matlab.ui.control.DropDown
        ComparingResultsTab             matlab.ui.container.Tab
        ComparePanel                    matlab.ui.container.Panel
        graph_Primary_ComparisonTab     matlab.ui.control.UIAxes
        graph_Secondary_ComparisonTab   matlab.ui.control.UIAxes
        selectExperiment_Secondary      matlab.ui.control.DropDown
        ComparetoExperimentNoLabel      matlab.ui.control.Label
        TimestepminLabel                matlab.ui.control.Label
        selectTimestep_Secondary        matlab.ui.control.DropDown
        DisplayonrightaxisLabel_2       matlab.ui.control.Label
        y2Axis_Primary_ComparisonTab    matlab.ui.control.DropDown
        DisplayonrightaxisLabel_3       matlab.ui.control.Label
        y2Axis_Secondary_ComparisonTab  matlab.ui.control.DropDown
        DisplayonleftaxisDropDownLabel  matlab.ui.control.Label
        y1Axis_Primary_ComparisonTab    matlab.ui.control.DropDown
        DisplayonleftaxisDropDown_2Label  matlab.ui.control.Label
        y1Axis_Secondary_ComparisonTab  matlab.ui.control.DropDown
        RockData_ComparePage_Primary    matlab.ui.control.Table
        RockData_ComparePage_Secondary  matlab.ui.control.Table
        Compare2Tab                     matlab.ui.container.Tab
        graph_Primary_ComparisonTab2    matlab.ui.control.UIAxes
        graph_secondary_ComparisonTab2  matlab.ui.control.UIAxes
        LeftAxisDropDownLabel           matlab.ui.control.Label
        y1Axis_Primary_ComparisonTab2   matlab.ui.control.DropDown
        xAxisDropDown_2Label            matlab.ui.control.Label
        xAxis_Primary_ComparisonTab2    matlab.ui.control.DropDown
        RightAxisDropDownLabel          matlab.ui.control.Label
        y2Axis_Primary_ComparisonTab2   matlab.ui.control.DropDown
        LeftAxisDropDown_2Label         matlab.ui.control.Label
        y1Axis_Secondary_ComparisonTab2  matlab.ui.control.DropDown
        xAxisDropDownLabel              matlab.ui.control.Label
        xAxis_Secondary_ComparisonTab2  matlab.ui.control.DropDown
        RightAxisDropDown_2Label        matlab.ui.control.Label
        y2Axis_Secondary_ComparisonTab2  matlab.ui.control.DropDown
    end

    
    properties (Access = public)
    %any variables that must be accessed throughout the App must be declared here    
       
         db % Database connection
         
         %variables for primary experiment loaded
         experimentNo_Primary
         db_specimendata_Primary
         db_metadata_Primary
         db_data_Primary
         experimentsData_Primary
         permeabilityData_Primary
         permeabilityTimestep_Primary
         
         %variables for secondary experiment loaded
         experimentNo_Secondary
         db_specimendata_Secondary
         db_metadata_Secondary
         db_data_Secondary
         experimentsData_Secondary
         permeabilityData_Secondary
         permeabilityTimestep_Secondary
         
         Analytics_timeStep %new Table (chosen timestep)
         weight_outliers %weight outliers graph
         plotCorrectedGraphWeight %corrected weight graph 
         Ylimit % y limits of permeability graph
         
         loadingProgBar % Progressbar while loading or calculating
         
         %variables for dropdown menus
         y1Plot_TimelogTab
         y2Plot_TimelogTab
         y1Plot_Primary_ComparisonTab
         y2Plot_Primary_ComparisonTab
         y1Plot_Secondary_ComparisonTab
         y2Plot_Secondary_ComparisonTab
         y1Plot_Primary_ComparisonTab2
         y2Plot_Primary_ComparisonTab2
         y1Plot_Secondary_ComparisonTab2
         y2Plot_Secondary_ComparisonTab2
         xPlot_Primary_ComparisonTab2
         xPlot_Secondary_ComparisonTab2
         
         %variables containing the graphs
         deformation_mean_graph
         fluid_p_graph
         hydraulicCylinder_graph
         sigma2_3_graph
         weight_graph
         weight_diff_graph
         delta_deformation_1_graph
         delta_deformation_2_graph
         
         plotProperties
         
         timelog %timelog table
         %TimeStep %selected timestep
         
         %permeability_timeStep %permeability of selected timestep
         plotline %Line plot to mark timelog events
         plottext %number of timelog event

         %Analytics_compare %Data of compared experiment 

         %PermPlot %permeabiity plot
         %PlotPerm_timestep %permeability plot from new timestep
         eventRow
         
         %smoothPlot%smooth plot
         %smoothPlotTS%smooth plot from new timestep
         %ComparePlot%permeability plot for compare experiment
         %experiment_permeability %permeability for compare experiment
         %experiment_data %experiment data for compare experiment
         %experiment_specimendata% specimendata for compare experiment
         %experiment_num
    end
    
    methods (Access = private)

        function result = changeGraphDataSet(app, selectedDataset, selectedYValue, plotVar, graphVar, position, selectedXValue)
        %Function to plot graphs with given datasets into given graphs
        %depending on the handed over selected dataset (selected via
        %dropdown).
        
             try
                %The dataTable has to be selected depending on the dataset
                %(primary or secondary). And the two tables with
                %experimentsdatas (secondly) and permeabilitydata (e.g.
                %minutly) have to be synchronized.
                switch selectedDataset
                    case 'primary'                        
                        dataTable = synchronize(app.experimentsData_Primary, app.permeabilityData_Primary, 'first','linear');                                 
                    
                    case 'secondary'
                        dataTable = synchronize(app.experimentsData_Secondary, app.permeabilityData_Secondary, 'first','linear');
                        
                    otherwise
                        return
                end
                
                switch selectedXValue
                    case 'runtime'
                        xAxisLabel= 'Runtime [hh:mm]';
                        xAxisData=dataTable.runtime_1;
                    
                    case 'permeability'
                        xAxisLabel = 'Permeability in m/s';
                        xAxisData = dataTable.permeability; 
                        
                    case 'permeability_smooth'
                        xAxisLabel = 'Permeability (smooth) in m/s';
                        xAxisData = dataTable.permeability_smooth; 
                    
                    case 'deformation_mean'
                        xAxisLabel = 'Deformation in mm';
                        xAxisData = dataTable.deformation_mean;
                        
                    case 'hydrCylinder_p_rel'
                        xAxisLabel = 'Pressure of the Hydraulic Cylinder in Bar';
                        xAxisData = dataTable.hydrCylinder_p_rel;
                        
                    case 'fluid_p_rel'
                        xAxisLabel = 'Fluid Pressure in bar';
                        xAxisData = dataTable.fluid_p_rel;
                      
                    case 'sigma2_3_p_rel'
                        xAxisLabel = 'Confining Pressure in bar';
                        xAxisData = dataTable.sigma2_3_p_rel;
                        
                    case 'pump_sum'
                        xAxisLabel = 'Pump pressure in bar';
                        xAxisData = dataTable.pump_sum;
                    
                    case 'weight'
                        xAxisLabel = 'Weight in kg';
                        xAxisData = dataTable.weight;
                        
                    case 'weight_diff'
                        xAxisLabel = 'Weight difference in kg';
                        xAxisData = dataTable.weight_diff_1;
                        
                    case 'fluid_out_t'
                        xAxisLabel = 'Fluid Temperature in Degrees Celsius';
                        xAxisData = dataTable.fluid_out_t;
                        
                    case 'room_t'
                        xAxisLabel = 'Room Temperature in Degrees Celsius';
                        xAxisData = dataTable.room_t;
                    
                    otherwise
                        xAxisData=dataTable.runtime_1;
                        xAxisLabel='Runtime [hh:mm]';
                end

%                 xAxisData = dataTable.runtime_1;
                
                %Yaxis label and data are depending on the selected database
                switch selectedYValue
                    case 'permeability'
                        yAxisLabel = 'Permeability in m/s';
                        yAxisData = dataTable.permeability; 
                        
                    case 'permeability_smooth'
                        yAxisLabel = 'Permeability (smooth) in m/s';
                        yAxisData = dataTable.permeability_smooth; 
                    
                    case 'deformation_mean'
                        yAxisLabel = 'Deformation in mm';
                        yAxisData = dataTable.deformation_mean;
                        
                    case 'hydrCylinder_p_rel'
                        yAxisLabel = 'Pressure of the Hydraulic Cylinder in Bar';
                        yAxisData = dataTable.hydrCylinder_p_rel;
                        
                    case 'fluid_p_rel'
                        yAxisLabel = 'Fluid Pressure in bar';
                        yAxisData = dataTable.fluid_p_rel;
                      
                    case 'sigma2_3_p_rel'
                        yAxisLabel = 'Confining Pressure in bar';
                        yAxisData = dataTable.sigma2_3_p_rel;
                        
                    case 'pump_sum'
                        yAxisLabel = 'Pump pressure in bar';
                        yAxisData = dataTable.pump_sum;
                    
                    case 'weight'
                        yAxisLabel = 'Weight in kg';
                        yAxisData = dataTable.weight;
                        
                    case 'weight_diff'
                        yAxisLabel = 'Weight difference in kg';
                        yAxisData = dataTable.weight_diff_1;
                        
                    case 'fluid_out_t'
                        yAxisLabel = 'Fluid Temperature in Degrees Celsius';
                        yAxisData = dataTable.fluid_out_t;
                        
                    case 'room_t'
                        yAxisLabel = 'Room Temperature in Degrees Celsius';
                        yAxisData = dataTable.room_t;
                    
                    case 'delta_deformation_1'
                        yAxisLabel = 'Deviation of Sensor 1 from the mean';
                        yAxisData = dataTable.delta_deformation_1;
                    
                    case 'delta_deformation_2'
                        yAxisLabel = 'Deviation of Sensor 2 from the mean';
                        yAxisData = dataTable.delta_deformation_2;
                        
                    otherwise
                        yAxisLabel = '';
                        yAxisData=dataTable.permeability;
                end
                
                %check if the dataset shall be printed on the left or the right side
                if ~strcmp(selectedYValue, 'none')
                    switch position
                        case 'left'
                            
                            yyaxis(graphVar, 'left');
                            %%still showing duration error
                           switch  selectedXValue  
                               case 'runtime'
                            plotVar = plot(graphVar, xAxisData, yAxisData,'DurationTickFormat', 'hh:mm','DisplayName',yAxisLabel);
                               otherwise
                            cla(graphVar,'reset');
                            plotVar = plot(graphVar, xAxisData, yAxisData,'DisplayName',yAxisLabel);
                            end 
                            
                            plotVar.Color = app.plotProperties.lineColor('left');
                            plotVar.LineStyle = app.plotProperties.lineStyle('left');
                            plotVar.Marker = app.plotProperties.marker('left');
                            ylabel(graphVar, yAxisLabel);
                            xlabel(graphVar, xAxisLabel);
                            graphVar.YColor = app.plotProperties.lineColor('left');
                            graphVar.BackgroundColor='white';
                            legend(graphVar,'-DynamicLegend');
                            hold (graphVar, 'on') 
                            
                        case 'right'
                            yyaxis(graphVar, 'right');
                            
                            switch  selectedXValue  
                               case 'runtime'
                            plotVar = plot(graphVar, xAxisData, yAxisData, 'DurationTickFormat', 'hh:mm','DisplayName',yAxisLabel);
                               otherwise
                            plotVar = plot(graphVar, xAxisData, yAxisData,'DisplayName',yAxisLabel);
                            end
                            
                            plotVar.Color = app.plotProperties.lineColor('right');
                            plotVar.LineStyle = app.plotProperties.lineStyle('right');
                            plotVar.Marker = app.plotProperties.marker('right');
                            ylabel(graphVar, yAxisLabel);
                            xlabel(graphVar, xAxisLabel);
                            graphVar.YColor = app.plotProperties.lineColor('right');
                            graphVar.BackgroundColor='white';
                            legend(graphVar, '-DynamicLegend');
                            hold (graphVar, 'on')
                    end   
                    
                    result = plotVar;
                 else
                    result = 0;
                 end
                
            catch
                uialert(app.Triaxial_Experiment_Results, 'An error occured while loading a new dataset for the selected graph.', 'warning');
                result = 0;
                return;
             end
            
        end
        
            
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        %Function that is called after gui components are initialized. It
        %develops the connection to the database and loads a list of all
        %available experiments.
        %All dropdown menus will be initialized and filled with data,
        %especcialy the dropdown menu to choose an experiment.
        
        import MeridDB.*
            
            %Initialize property-table for plots/graphs
            app.plotProperties = table('Size', [2,3], 'VariableTypes', {'string', 'string', 'string'}, 'RowNames', {'left';'right'}, 'VariableNames',{'lineColor','marker','lineStyle'});
            app.plotProperties.lineColor('left') = '#0072BD';
            app.plotProperties.marker('left') = 'none';
            app.plotProperties.lineStyle('left') = '-';
            app.plotProperties.lineColor('right') = '#D95319';
            app.plotProperties.marker('right') = 'none';
            app.plotProperties.lineStyle('right') = '-.';
            
            %Initialize database
            app.db = MeridDB('hiwi_ro','#meridDB2019','134.130.87.47',3306);
            
            %Request a list of all experiments and print out in table
                %Request list of experiments
                experimentOverview = app.db.getExperiments;
                
                %Format datetime-variables
                experimentOverview.time_start.Format = 'dd.MM.uu HH:mm';
                experimentOverview.time_end.Format = 'dd.MM.uu HH:mm';
                
                %Output
                app.experimentsOverviewTable.Data = experimentOverview;
                app.experimentsOverviewTable.ColumnName = {'#','Short','Begin','End'};
            
            
            %Initialization of dropdowns
                %Experiment-selection dropdown
                dropDownList_Text = ["- no selection -"; strcat(string(experimentOverview.experiment_no), " - ", string(experimentOverview.short))];
                dropDownList_Data = [0; experimentOverview.experiment_no];
                app.selectExperiment_Primary.Items = dropDownList_Text;
                app.selectExperiment_Primary.ItemsData = dropDownList_Data;
                app.selectExperiment_Primary.Tooltip='The permeability will be calculated using a default value of 5 minutes. To select a different timestep, use the Dropdown on the "Results" page.';
                app.xAxis_Primary_ComparisonTab2.Tooltip='This dropdown responds to the data of the LEFT dropdown only.';
                app.xAxis_Secondary_ComparisonTab2.Tooltip='This dropdown responds to the data of the LEFT dropdown only.';
                
                app.selectExperiment_Secondary.Items = app.selectExperiment_Primary.Items;
                app.selectExperiment_Secondary.ItemsData = app.selectExperiment_Primary.ItemsData;
            
                %Permeability timestep-selection dropdown
                app.selectTimestep_Primary.Items = ['- no selection -'; string([1; 2; 5; 10; 15; 20; 25; 30; 45; 60])];
                app.selectTimestep_Primary.ItemsData = [0; 1; 2; 5; 10; 15; 20; 25; 30; 45; 60];
                app.selectTimestep_Secondary.Items = app.selectTimestep_Primary.Items;
                app.selectTimestep_Secondary.ItemsData = app.selectTimestep_Primary.ItemsData;
            
                %Dataset-selection dropdown for graphs
                dropDownList_Text = {'- no selection -', 'Permeability', 'Permeability (smoothed)', 'Deformation_mean', 'Hydraulic Cylinder', 'Fluid Pressure', 'Confining Pressure', 'Pump pressure', 'Weight Difference', 'Fluid Temperature', 'Room Temperature'};
                dropDownList_Data = {'none'; 'permeability'; 'permeability_smooth'; 'deformation_mean'; 'hydrCylinder_p_rel'; 'fluid_p_rel'; 'sigma2_3_p_rel'; 'pump_sum'; 'weight_diff'; 'fluid_out_t'; 'room_t'};
                dropDownList_Text_xAxis={'runtime', 'Permeability', 'Permeability (smoothed)', 'Deformation_mean', 'Hydraulic Cylinder', 'Fluid Pressure', 'Confining Pressure', 'Pump pressure', 'Weight Difference', 'Fluid Temperature', 'Room Temperature'};
                dropDownList_Data_xAxis = {'runtime_1'; 'permeability'; 'permeability_smooth'; 'deformation_mean'; 'hydrCylinder_p_rel'; 'fluid_p_rel'; 'sigma2_3_p_rel'; 'pump_sum'; 'weight_diff'; 'fluid_out_t'; 'room_t'};
                
                
                app.y2Axis_Primary_ComparisonTab.Items = dropDownList_Text;
                app.y2Axis_Primary_ComparisonTab.ItemsData = dropDownList_Data;
                app.y2Axis_Secondary_ComparisonTab.Items = dropDownList_Text;
                app.y2Axis_Secondary_ComparisonTab.ItemsData = dropDownList_Data;
                app.y2Axis_TimelogTab.Items = dropDownList_Text;
                app.y2Axis_TimelogTab.ItemsData = dropDownList_Data;
                app.y1Axis_TimelogTab.Items = dropDownList_Text;
                app.y1Axis_TimelogTab.ItemsData = dropDownList_Data;
                app.y1Axis_Primary_ComparisonTab.Items = dropDownList_Text;
                app.y1Axis_Primary_ComparisonTab.ItemsData = dropDownList_Data;
                app.y1Axis_Secondary_ComparisonTab.Items = dropDownList_Text;
                app.y1Axis_Secondary_ComparisonTab.ItemsData = dropDownList_Data;
                app.y1Axis_Primary_ComparisonTab2.Items = dropDownList_Text;
                app.y1Axis_Primary_ComparisonTab2.ItemsData = dropDownList_Data;
                app.y2Axis_Primary_ComparisonTab2.Items = dropDownList_Text;
                app.y2Axis_Primary_ComparisonTab2.ItemsData = dropDownList_Data;
                app.xAxis_Primary_ComparisonTab2.Items = dropDownList_Text_xAxis;
                app.xAxis_Primary_ComparisonTab2.ItemsData = dropDownList_Data_xAxis;
                app.y1Axis_Secondary_ComparisonTab2.Items = dropDownList_Text;
                app.y1Axis_Secondary_ComparisonTab2.ItemsData = dropDownList_Data;
                app.y2Axis_Secondary_ComparisonTab2.Items = dropDownList_Text;
                app.y2Axis_Secondary_ComparisonTab2.ItemsData = dropDownList_Data;
                app.xAxis_Secondary_ComparisonTab2.Items = dropDownList_Text_xAxis;
                app.xAxis_Secondary_ComparisonTab2.ItemsData = dropDownList_Data_xAxis;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %This part should be put into another function and should be
            %handeld by the tabs and ther children
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %panel visibility is off for all panels before calculation 
            app.rockDataPanel.Visible = 'off';
            app.DataAndWeightPanel.Visible = 'off';
            app.FluidFlowAndDeformationPanel.Visible = 'off';
            app.permeabilityResultsPanel.Visible = 'off';
            app.graph_Primary_ComparisonTab.Visible = 'off';
            app.selectTimestep_Secondary.Visible = 'off';
            app.ComparePanel.Visible = 'off';
            app.y1Axis_Secondary_ComparisonTab.Visible = 'off';
            app.y2Axis_Secondary_ComparisonTab.Visible = 'off';
    
        end

        % Value changed function: displayOutliersWeight
        function displayOutliersWeightValueChanged(app, event)
        %Function that handels a checkbox change for displaying the
        %outliers in the weight on the scale.
        
            %display outliers for weight
            checkboxValue = app.displayOutliersWeight.Value;
            
            if checkboxValue == 1 
                TF = isoutlier(app.experimentsData_Primary.weight_diff, 'mean');
                x = app.experimentsData_Primary.runtime;
                y = app.experimentsData_Primary.weight_diff;
                
                yyaxis(app.graphWeight, 'right')
                app.weight_outliers = plot(app.graphWeight, x(TF), y(TF), 'rx', 'HandleVisibility', 'off');
            
            else
                delete(app.weight_outliers)
                
            end
         
        end

        % Value changed function: correctedGraphWeight
        function correctedGraphWeightValueChanged(app, event)
        %Function that handels a checkbox change for displaying the
        %outliers in the weight on the scale.
        
            %display corrected weight curve     
            checkboxValue = event.Value;
                
            if checkboxValue == 1
                %delete current weight diff graph
                delete(app.weight_diff_graph)
                delete(app.weight_outliers)
                app.displayOutliersWeight.Value=0;
                x = app.permeabilityData_Primary.runtime;
                y=app.permeabilityData_Primary.weight_diff;
                yyaxis(app.graphWeight, 'right')
                app.plotCorrectedGraphWeight = plot(app.graphWeight, x, y, 'm-', 'HandleVisibility', 'off');
               
            else %delete plot if box is unchecked
                delete(app.plotCorrectedGraphWeight)
                %plot original weight_diff graph
                app.weight_diff_graph= app.changeGraphDataSet(...
                    'primary', ...
                    'weight_diff', ...
                    app.weight_diff_graph, ...
                    app.graphWeight, ...
                    'right','runtime');
                  
            end
        end

        % Value changed function: y2Axis_TimelogTab
        function y2Axis_TimelogTabValueChanged(app, event)
            
            dropdownSelection = event.Value;
            
            try
                delete(app.y2Plot_TimelogTab);
            catch
            end
            
            try
                app.y2Plot_TimelogTab = app.changeGraphDataSet( ...
                    'primary', ...
                    dropdownSelection, ...
                    app.y2Plot_TimelogTab, ...
                    app.graph_TimelogTab, ...
                    'right','runtime');    
            catch
            end
        
        end

        % Value changed function: selectTimestep_Primary
        function selectTimestep_PrimaryValueChanged(app, event)
            
            %alert user if no timestep was selecetd
            if (app.selectTimestep_Primary.Value == 0)
                uialert(app.Triaxial_Experiment_Results,'No timestep was selected.','Warning');
                return;
            end
            
            app.permeabilityTimestep_Primary = app.selectTimestep_Primary.Value;
                
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Show progress bar initialization
            titleText = strcat('Please Wait - Recalculating experiment #', string(app.experimentNo_Primary));
            messageText = 'Recalculating permeability';
            app.loadingProgBar = uiprogressdlg(app.Triaxial_Experiment_Results, 'Title', titleText, 'Message', messageText, 'Indeterminate','on');  
            
            %get new tabe and permeability calculation 
            app.permeabilityData_Primary = app.db_data_Primary.getPermeability( ...
                app.db_specimendata_Primary.height, ...
                app.db_specimendata_Primary.diameter, ...
                app.permeabilityTimestep_Primary); 
            app.permeabilityData_Primary.permeability_smooth = filloutliers(app.permeabilityData_Primary.permeability, 'nearest', 'movmedian', 10000);
            
            app.y1Axis_TimelogTab.Value = 'permeability';
            dropdownSelection = app.y1Axis_TimelogTab.Value;
            
            try
                delete(app.y1Plot_TimelogTab);
                app.y1Plot_TimelogTab = app.changeGraphDataSet( ...
                    'primary', ...
                    dropdownSelection, ...
                    app.y1Plot_TimelogTab, ...
                    app.graph_TimelogTab, ...
                    'left');
            catch
                uialert(app.Triaxial_Experiment_Results, 'An error occured while loading a new dataset for the selected graph.', 'warning');
            end
            
            app.y1Axis_Primary_ComparisonTab.Value = 'permeability';
            dropdownSelection = app.y1Axis_Primary_ComparisonTab.Value;
            
            try
                delete(app.y1Plot_Primary_ComparisonTab);
                app.y1Plot_Primary_ComparisonTab = app.changeGraphDataSet(...
                    'primary', ...
                    dropdownSelection, ...
                    app.y1Plot_Primary_ComparisonTab, ...
                    app.graph_Primary_ComparisonTab, ...
                    'left');  
            catch
                uialert(app.Triaxial_Experiment_Results, 'An error occured while loading a new dataset for the selected graph.', 'warning');
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Progress bar closing
            close(app.loadingProgBar);  
        end

        % Value changed function: selectExperiment_Primary
        function selectExperiment_PrimaryValueChanged(app, event)
        
            %Reads the ItemsData not the ItemName
            %The value contains only the experiment number as numeric
            value = app.selectExperiment_Primary.Value;            
            
            %display alert if no experiment was selected
            if ~isnumeric(value(1)) %check if selection is numerical
                %Stop execution when no experiment was selected
                uialert(app.Triaxial_Experiment_Results, 'No experiment was selected.', 'Error');
                return;
            end
            
            %Save selected experiment number as variable in app
            app.experimentNo_Primary = value; 
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Show progress bar initialization
            titleText = strcat('Please Wait - Loading experiment #', string(app.experimentNo_Primary));
            messageText = 'Loading the metadata';
            app.loadingProgBar = uiprogressdlg(app.Triaxial_Experiment_Results, 'Title', titleText, 'Message', messageText);
            pause(0.3);
            app.loadingProgBar.Value=0.1; %Set progress of progressbar to 10% 
            
            %Clear graphs and tables and timelog
            cla(app.graphWeight,'reset')
            cla(app.graphDeformation,'reset')
            cla(app.graphFluidFlow,'reset')
            cla(app.graphOverview,'reset')
            cla(app.graph_TimelogTab, 'reset')
            cla(app.graph_Primary_ComparisonTab, 'reset')
            
            app.displayOutliersWeight.Value = 0;
            app.correctedGraphWeight.Value = 0;
            app.timelogTableActual.ColumnName = [];
            app.y2Axis_TimelogTab.Value = 'none';
            app.y2Axis_Primary_ComparisonTab.Value = 'none';
            
            %Try to load metadata and specimendata from database und show them in a table
            try
                %Get metadata from database
                app.db_metadata_Primary = app.db.getMetaData((app.experimentNo_Primary));
                
                %Check if metadata was submitted, otherwise exit function
                if (app.db_metadata_Primary == 0)
                    app.Triaxial_Experiment_Results.Name = strcat('MERID Datainspector');
                    %Stop execution when no metadata was found
                    uialert(app.Triaxial_Experiment_Results, 'No metadata for selected experiment found.', 'warning');
                    close(app.loadingProgBar);
                    return;
                    
                else
                    app.Triaxial_Experiment_Results.Name = ['MERID Datainspector - Experiment: ', app.db_metadata_Primary.short];
                    
                end
 
                %Get specimen and rock data from database
                app.db_specimendata_Primary = app.db.getSpecimenData(app.experimentNo_Primary, app.db_metadata_Primary.specimenId);
                
                %Check if specimen data was submitted, otherwise exit function
                if (app.db_specimendata_Primary == 0)
                    %Stop execution when no metadata was found
                    uialert(app.Triaxial_Experiment_Results, 'No specimendata for selected experiment found.', 'warning');
                    close(app.loadingProgBar);
                    return;
                end

            
                %Display rock data in Table
                rockdata = app.db_specimendata_Primary.dataTable;
                rockdata.mass_sat = round((rockdata.mass_sat/1000),2);
                rockdata.mass_wet = round((rockdata.mass_wet/1000),2);
                rockdata.mass_dry = round((rockdata.mass_dry/1000),2);
                rockdata.height = round(rockdata.height,2);
                rockdata.diameter = round(rockdata.diameter,2);
                rockdata = rows2vars(rockdata);
                
                %Set table captions, extract units from table-units
                rockdata(:,1) = {'Specimen ID';'Specimen Name'; ...
                    strcat('Height [',app.db_specimendata_Primary.dataTable.Properties.VariableUnits('height'),']'); ...
                    strcat('Diameter [',app.db_specimendata_Primary.dataTable.Properties.VariableUnits('diameter'),']'); ...
                    strcat('Mass Saturated [',app.db_specimendata_Primary.dataTable.Properties.VariableUnits('mass_sat'),']'); ...
                    strcat('Mass Wet [',app.db_specimendata_Primary.dataTable.Properties.VariableUnits('mass_wet'),']'); ...
                    strcat('Mass Dry [',app.db_specimendata_Primary.dataTable.Properties.VariableUnits('mass_dry'),']'); ...
                    'Rock name'; ...
                    'Description'; ...
                    strcat('Density Wet [',app.db_specimendata_Primary.dataTable.Properties.VariableUnits('density_wet'),']'); ...
                    strcat('Density Saturated [',app.db_specimendata_Primary.dataTable.Properties.VariableUnits('density_sat'),']'); ...
                    strcat('Density Dry [',app.db_specimendata_Primary.dataTable.Properties.VariableUnits('density_dry'),']'); ...
                    strcat('Density Grain [',app.db_specimendata_Primary.dataTable.Properties.VariableUnits('density_grain'),']'); ...
                    strcat('Permeability Coefficient [',app.db_specimendata_Primary.dataTable.Properties.VariableUnits('perm_coeff'),']'); ...
                    'Porosity'; ...
                    'Void Ratio'; ...
                    strcat('Uniaxial Compression Strength [',app.db_specimendata_Primary.dataTable.Properties.VariableUnits('uniAx_comp_strength'),']'); ...
                    strcat('Uniaxial Emodulus [',app.db_specimendata_Primary.dataTable.Properties.VariableUnits('uniAx_emodulus'),']') ...
                    };
                
                %Write rockdata to table in GUI
                app.RockDataTable.RowName = []; %clear column property
                app.rockDataPanel.Title = ('Rock Data');
                app.RockDataTable.Data = rockdata;
                app.RockDataTable.ColumnName = {'Property','Value'};
                app.rockDataPanel.Visible = 'on';
                
                %Rock Data Compare Page
                ValueColumn_primary=[app.db_specimendata_Primary.rockDensityDry;...
                    round(app.db_specimendata_Primary.rockDensityGrain,4);...
                    round(app.db_specimendata_Primary.porosity,4);
                    app.db_specimendata_Primary.uniAxialCompressiveStrength];
                PropertyColumn_Primary={'Density Dry'; 'Density Grain'; 'Porosity'; 'Uniaxial Compressive Strength'};
                
                RockData_table=table(PropertyColumn_Primary,ValueColumn_primary);
                RockData_table(:,1) ={
                    strcat('Density Dry [',app.db_specimendata_Primary.dataTable.Properties.VariableUnits('density_dry'),']'); ...
                    strcat('Density Grain [',app.db_specimendata_Primary.dataTable.Properties.VariableUnits('density_grain'),']'); ...
                    'Porosity'; ...
                    strcat('Uniaxial Compression Strength [',app.db_specimendata_Primary.dataTable.Properties.VariableUnits('uniAx_comp_strength'),']'); ...
                    };
                app.RockData_ComparePage_Primary.Data=RockData_table;
                
            catch
                uialert(app.Triaxial_Experiment_Results, 'An error occured while loading specimen- oder metadata for the selected experiment.', 'warning');
                close(app.loadingProgBar);
                return;
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Progress bar update
            messageText = ['Loading the experiments-data. ', 'This could take a couple of minutes!'];
            app.loadingProgBar = uiprogressdlg(app.Triaxial_Experiment_Results, 'Title', titleText, 'Message', messageText, 'Value', 0.2);
            
            %Try to load experiment data from database
            try
                app.db_data_Primary = app.db.getExperimentData(app.experimentNo_Primary); 
            
                %Load relevant experiments-data in local variable
                app.experimentsData_Primary = app.db_data_Primary.getAnalyticsDataForGUI();
                
            catch
                uialert(app.Triaxial_Experiment_Results, 'No experiments-data for selected experiment found. Data import aborted. Please try again.', 'warning');
                %throw(E);
                close(app.loadingProgBar);
                return;
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Progress bar update
            messageText = 'Calculating permeability';
            app.loadingProgBar = uiprogressdlg(app.Triaxial_Experiment_Results, 'Title', titleText, 'Message', messageText, 'Value', 0.5);
            
            %Try to calculate permeability
            try            
                %Calculate permeability
                app.selectTimestep_Primary.Value = 5;
                app.permeabilityData_Primary = app.db_data_Primary.getPermeability(app.db_specimendata_Primary.height, app.db_specimendata_Primary.diameter); %Leaving the timestep-option out for default: 5 minutes
                app.permeabilityData_Primary.permeability_smooth = filloutliers(app.permeabilityData_Primary.permeability, 'nearest', 'movmedian', 10000);
                
            catch
                uialert(app.Triaxial_Experiment_Results, 'Error while calculating permeability. Please try again.', 'warning');
                close(app.loadingProgBar);
                return;
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %THE FOLLOWING PART SHOULD BE IN ANOTHER FUNCTION THAT WILL BE
            %CALLES IN THIS PLACE!!!
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Progress bar update
            messageText = 'Display all data';
            app.loadingProgBar = uiprogressdlg(app.Triaxial_Experiment_Results, 'Title', titleText, 'Message', messageText, 'Value', 0.7);
            
                  
            %GRAPH OVERVIEW containing all relevant measurements
              
            app.changeGraphDataSet(...
                    'primary', ...
                    'deformation_mean', ...
                    app.deformation_mean_graph, ...
                    app.graphOverview, ...
                    'left','runtime');
                   
            
             app.changeGraphDataSet(...
                    'primary', ...
                    'fluid_p_rel', ...
                    app.fluid_p_graph, ...
                    app.graphOverview, ...
                    'right','runtime');
                
             app.changeGraphDataSet(...
                    'primary', ...
                    'hydrCylinder_p_rel', ...
                    app.hydraulicCylinder_graph, ...
                    app.graphOverview, ...
                    'right','runtime');
                                    
             app.changeGraphDataSet(...
                    'primary', ...
                    'sigma2_3_p_rel', ...
                    app.sigma2_3_graph, ...
                    app.graphOverview, ...
                    'right','runtime');
                    
            title(app.graphOverview, 'Data Overview');

            %GRAPH FLUID FLOW containing fluid pressure
            
            app.changeGraphDataSet(...
                    'primary', ...
                    'fluid_p_rel', ...
                    app.fluid_p_graph, ...
                    app.graphFluidFlow, ...
                    'left','runtime');
                    
            title(app.graphFluidFlow, 'Fluid Flow Data');
            
            %GRAPH WEIGHT containing weight and weight difference
                            
             app.changeGraphDataSet(...
                    'primary', ...
                    'weight', ...
                    app.weight_graph, ...
                    app.graphWeight, ...
                    'left','runtime');
            legend(app.graphWeight,'weight');     
             
             app.weight_diff_graph= app.changeGraphDataSet(...
                    'primary', ...
                    'weight_diff', ...
                    app.weight_diff_graph, ...
                    app.graphWeight, ...
                    'right','runtime');
                    
            title(app.graphWeight, 'Weigth and Weight Difference'); 
            
            %GRAPH DEFORMATION
            app.changeGraphDataSet(...
                    'primary', ...
                    'deformation_mean', ...
                    app.deformation_mean_graph, ...
                    app.graphDeformation, ...
                    'left','runtime');
                    
            app.changeGraphDataSet(...
                    'primary', ...
                    'delta_deformation_1', ...
                    app.delta_deformation_1_graph, ...
                    app.graphDeformation, ...
                    'right','runtime');
                    
            app.changeGraphDataSet(...
                    'primary', ...
                    'delta_deformation_2', ...
                    app.delta_deformation_2_graph, ...
                    app.graphDeformation, ...
                    'right','runtime');
                    
            
            title(app.graphDeformation, 'Deformation and Deviation of Sensors');

            
            %make all panels visible
            app.DataAndOutliersTab.HandleVisibility='on';
            app.DataAndWeightPanel.Visible='on';
            app.FluidFlowAndDeformationPanel.Visible='on';
            app.permeabilityResultsPanel.Visible='on';
            app.ComparePanel.Visible='on';
            
            %Plot y1-axis in graph on timetable tab
            %Plot Permeability
                app.y1Axis_TimelogTab.Value = 'permeability';                
                dropdownSelection = app.y1Axis_TimelogTab.Value;
            
                try
                    delete(app.y1Plot_TimelogTab);
                    app.y1Plot_TimelogTab = app.changeGraphDataSet( ...
                        'primary', ...
                        dropdownSelection, ...
                        app.y1Plot_TimelogTab, ...
                        app.graph_TimelogTab, ...
                        'left','runtime');
                        
                catch
                    
                end
    
                app.graph_TimelogTab.Visible='on'; 

               
            %Plot y1-axis in primary graph on comparison tab
            %Plot Permeability
                app.y1Axis_Primary_ComparisonTab.Value = 'permeability';
                dropdownSelection = app.y1Axis_Primary_ComparisonTab.Value;
                
                try
                    delete(app.y1Plot_Primary_ComparisonTab);
                    app.y1Plot_Primary_ComparisonTab = app.changeGraphDataSet(...
                        'primary', ...
                        dropdownSelection, ...
                        app.y1Plot_Primary_ComparisonTab, ...
                        app.graph_Primary_ComparisonTab, ...
                        'left','runtime');
                           
                catch
                    uialert(app.Triaxial_Experiment_Results, 'An error occured while loading a new dataset for the selected graph.', 'warning');
                end
    
                app.graph_Primary_ComparisonTab.Visible='on'; 
            
            %set TIMELOG data in table 
%                 try
                    if isempty(app.db_metadata_Primary.timeLog)
                        %empty data in tables
                        app.timelogTableActual.Data = [];
                        app.timelogTablePre.Data = [];
                        app.timelogTablePost.Data = [];
                        %add comment
                        app.timelogTableActual.ColumnName={'No timelog available'};
                        app.timelogTablePre.ColumnName = {'No timelog available'};
                        app.timelogTablePost.ColumnName = {'No timelog available'};
                        app.timelogTableActual.ColumnWidth = 'auto';
                        app.timelogTablePre.ColumnWidth = 'auto';
                        app.timelogTablePost.ColumnWidth = 'auto';
                        %remove events button
                        app.ClearEventsButton.Visible = 'off';
                     
                    else 
                        app.ClearEventsButton.Visible = 'on';
                        
                        %use any table that has not been retimed ... to find matching runtimes
                        %retime table as all timelog entries have format
                        %HH:MM
                        dataTable_for_timelog = retime(app.db_data_Primary.getFlowData,'minutely','linear');
                        
                        %Only use timelog entries that match the start and
                        %end times of the data table-- otherwise runtime
                        %cannot be added as array size does not match
                        timerangeTable = timerange(dataTable_for_timelog.time(1), dataTable_for_timelog.time(end), 'closed');
                        app.timelog = table2timetable(app.db_metadata_Primary.timeLog); %create timetable
                        app.timelog = app.timelog(timerangeTable,:);
                        app.timelog = timetable2table(app.timelog); %neccessary otherwise markers are not found
                        
                        %Find markers for time log events and add runtime to timelog
                        time_of_event = app.timelog.time;
                        markers = dataTable_for_timelog(time_of_event,:); %finds all rows of table corresponding to the datetime 
                        app.timelog.runtime = (markers.runtime); %add runtime to timelog
                        app.timelog.runtime.Format = ('hh:mm');
                        
                        %delete columns and sort table
                        app.timelog.log_id = []; 
                        app.timelog.experiment_no = [];
                        app.timelog.retrospective = [];
                        app.timelog = sortrows(app.timelog, 'time', 'ascend'); %sort timelog table
                        app.timelog.time.Format = 'dd-MM-yyyy HH:mm';
                        app.timelog.selectEvents = false(size(app.timelog.description)); %add checkbox column
                        
                        %set row name, colum name and data in main UITable
                        app.timelogTableActual.Data = app.timelog;
                        app.timelogTableActual.ColumnName = {'Time','Description','Runtime','Select Events'};
                        app.timelogTableActual.RowName = 'numbered';
                       
                %FIND entries outside of experiment timerange
                timelog_rest = app.db_metadata_Primary.timeLog;     
               
                     
                if app.db_metadata_Primary.time_start>timelog_rest.time(1) %all entries before start time
                              
                          start_of_entries=timelog_rest.time(1);
                          TR1=timerange(start_of_entries,timelog_rest.time(1),'closed'); %half open intervall
                          
                          timelogs_leftover1=table2timetable(timelog_rest);
                          timelogs_leftover1=timelogs_leftover1(TR1,:); %find all rows before start
                          timelogs_leftover1=timetable2table(timelogs_leftover1); %data for UITable must be table
                          timelogs_leftover1.log_id=[];
                          timelogs_leftover1.experiment_no=[];
                          timelogs_leftover1.retrospective=[];
                          
                          %add 'not in range' to runtime and select events column
                          none=cell(size(timelogs_leftover1.time));
                          none{1,1}='not in range';
                          timelogs_leftover1.runtime=none;
                          timelogs_leftover1.selectEvents=none;
                          
                else
                     
                          col1={'No entries before start of the experiment'};
                          timelogs_leftover1=table(col1);
                          app.timelogTablePre.ColumnWidth='auto';
                          app.timelogTablePre.ColumnName={'No Timelog'};
                end
                
                if app.db_metadata_Primary.time_end<timelog_rest.time(end) %all entries after end of experiment
                           TR2=timerange(app.db_metadata_Primary.time_end,timelog_rest.time(end),'closed');  
                          
                          timelogs_leftover2=table2timetable(timelog_rest);
                          timelogs_leftover2=timelogs_leftover2(TR2,:); %find all rows before start
                          timelogs_leftover2=timetable2table(timelogs_leftover2); %data for UITable must be table
                          timelogs_leftover2.log_id=[];
                          timelogs_leftover2.experiment_no=[];
                          timelogs_leftover2.retrospective=[];
                          
                          %add 'not in range' to runtime and select events column
                          none=cell(size(timelogs_leftover2.time));
                          none{1,1}='not in range';
                          timelogs_leftover2.runtime=none;
                          timelogs_leftover2.selectEvents=none;
                else 
                          col1={'No entries after end of the experiment'};
                          timelogs_leftover2=table(col1);
                          app.timelogTablePre.ColumnWidth='auto';
                          app.timelogTablePre.ColumnName={'No Timelog'};
                end 
                       
                          app.timelogTablePre.RowName='numbered';
                          app.timelogTablePre.ColumnName={'Time','Description','Runtime','Select Events'};
                          app.timelogTablePost.RowName='numbered';
                          app.timelogTablePost.ColumnName={'Time','Description','Runtime','Select Events'};


                        % set data in pre and post timelog tables
                        app.timelogTablePre.Data=timelogs_leftover1;
                        app.timelogTablePost.Data=timelogs_leftover2;
                     
                    end
%                 catch
%                     uialert(app.Triaxial_Experiment_Results, 'An error occured handling the timelog. No timelog for this experiment will be shown.', 'Warning');
%                 end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Progress bar closing
            close(app.loadingProgBar); 

        end

        % Value changed function: selectExperiment_Secondary
        function selectExperiment_SecondaryValueChanged(app, event)
            
            %Reads the ItemsData not the ItemName
            %The value contains only the experiment number as numeric
            value = app.selectExperiment_Secondary.Value;            
            
            %display alert if no experiment was selected
            if ~isnumeric(value(1)) %check if selection is numerical
                %Stop execution when no experiment was selected
                uialert(app.Triaxial_Experiment_Results, 'No experiment was selected.', 'Error');
                return;
            end
            
            %Save selected experiment number as variable in app
            app.experimentNo_Secondary = value;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Progress bar update
            titleText = strcat('Please Wait - Loading experiment #', string(app.experimentNo_Secondary));
            messageText = 'Loading the metadata';
            app.loadingProgBar = uiprogressdlg(app.Triaxial_Experiment_Results, 'Title', titleText, 'Message', messageText);
            pause(0.3);
            app.loadingProgBar.Value=0.1; %Set progress of progressbar to 10% 
            
            try
                %Get metadata from database
                app.db_metadata_Secondary = app.db.getMetaData((app.experimentNo_Secondary));
                
                %Check if metadata was submitted, otherwise exit function
                if (app.db_metadata_Secondary == 0)
                    %Stop execution when no metadata was found
                    uialert(app.Triaxial_Experiment_Results, 'No metadata for selected experiment found.', 'warning');
                    close(app.loadingProgBar);
                    return;
                end
 
                %Get specimen and rock data from database
                app.db_specimendata_Secondary = app.db.getSpecimenData(app.experimentNo_Secondary, app.db_metadata_Secondary.specimenId);
                
                %Check if specimen data was submitted, otherwise exit function
                if (app.db_specimendata_Secondary == 0)
                    %Stop execution when no metadata was found
                    uialert(app.Triaxial_Experiment_Results, 'No specimendata for selected experiment found.', 'warning');
                    close(app.loadingProgBar);
                    return;
                end

            catch
                uialert(app.Triaxial_Experiment_Results, 'An error occured while loading specimen- oder metadata for the selected experiment.', 'warning');
                close(app.loadingProgBar);
                return;
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Progress bar update
            messageText = ['Loading the experiments-data. ', 'This could take a couple of minutes!'];
            app.loadingProgBar = uiprogressdlg(app.Triaxial_Experiment_Results, 'Title', titleText, 'Message', messageText, 'Value', 0.2);
            
            %Try to load experiment data from database
            try
                app.db_data_Secondary = app.db.getExperimentData(app.experimentNo_Secondary); 
            
                %Load relevant experiments-data in local variable
                app.experimentsData_Secondary = app.db_data_Secondary.getAnalyticsDataForGUI();
                
            catch
                uialert(app.Triaxial_Experiment_Results, 'No experiments-data for selected experiment found. Data import aborted. Please try again.', 'warning');
                %throw(E);
                close(app.loadingProgBar);
                return;
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Rock Data Compare Page
                ValueColumn=[app.db_specimendata_Secondary.rockDensityDry;...
                    app.db_specimendata_Secondary.rockDensityGrain;...
                    app.db_specimendata_Secondary.porosity;
                    app.db_specimendata_Secondary.uniAxialCompressiveStrength];
                PropertyColumn={'Density Dry'; 'Density Grain'; 'Porosity'; 'Uniaxial Compressive Strength'};
                
                RockData_table=table(PropertyColumn,ValueColumn);
                RockData_table(:,1) ={
                    strcat('Density Dry [',app.db_specimendata_Secondary.dataTable.Properties.VariableUnits('density_dry'),']'); ...
                    strcat('Density Grain [',app.db_specimendata_Secondary.dataTable.Properties.VariableUnits('density_grain'),']'); ...
                    'Porosity'; ...
                    strcat('Uniaxial Compression Strength [',app.db_specimendata_Secondary.dataTable.Properties.VariableUnits('uniAx_comp_strength'),']'); ...
                    };
                app.RockData_ComparePage_Secondary.Data=RockData_table;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Progress bar update
            messageText = 'Calculating permeability';
            app.loadingProgBar = uiprogressdlg(app.Triaxial_Experiment_Results, 'Title', titleText, 'Message', messageText, 'Value', 0.5);
            
            %Try to calculate permeability
            try            
                %Calculate permeability
                app.selectTimestep_Secondary.Value = 5;
                app.permeabilityData_Secondary = app.db_data_Secondary.getPermeability(app.db_specimendata_Secondary.height, app.db_specimendata_Secondary.diameter); %Leaving the timestep-option out for default: 5 minutes
                
            catch
                uialert(app.Triaxial_Experiment_Results, 'Error while calculating permeability. Please try again.', 'warning');
                close(app.loadingProgBar);
                return;
            end
             
            %display permeability
%             try
                delete(app.y1Plot_Secondary_ComparisonTab);
                app.y1Plot_Secondary_ComparisonTab = app.changeGraphDataSet(...
                    'secondary', ...
                    'permeability', ...
                    app.y1Plot_Secondary_ComparisonTab, ...
                    app.graph_Secondary_ComparisonTab, ...
                    'left','runtime'); 
%             catch
%                 uialert(app.Triaxial_Experiment_Results, 'An error occured while loading a new dataset for the selected graph.', 'warning');
%             end
          
            
            %Show timestep selection after data sucesfully loaded            
            app.selectTimestep_Secondary.Visible = 'on';
            app.y1Axis_Secondary_ComparisonTab.Visible = 'on';
            app.y2Axis_Secondary_ComparisonTab.Visible = 'on';
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Progress bar closing
            close(app.loadingProgBar);
        end

        % Value changed function: selectTimestep_Secondary
        function selectTimestep_SecondaryValueChanged(app, event)
            
            %alert user if no timestep was selecetd
            if (app.selectTimestep_Secondary.Value == 0 || ~isnumeric(app.selectTimestep_Secondary.Value))
                uialert(app.Triaxial_Experiment_Results,'No timestep was selected.','Warning');
                return;
            end
            
            app.permeabilityTimestep_Secondary = app.selectTimestep_Secondary.Value;
                
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Show progress bar initialization
            titleText = strcat('Please Wait - Recalculating experiment #', string(app.experimentNo_Secondary));
            messageText = 'Recalculating permeability';
            app.loadingProgBar = uiprogressdlg(app.Triaxial_Experiment_Results, 'Title', titleText, 'Message', messageText, 'Indeterminate','on');  
            
            %get new tabe and permeability calculation 
            app.permeabilityData_Secondary = app.db_data_Secondary.getPermeability( ...
                app.db_specimendata_Secondary.height, ...
                app.db_specimendata_Secondary.diameter, ...
                app.permeabilityTimestep_Secondary); 
            
            app.y1Axis_Secondary_ComparisonTab.Value = 'permeability';
            dropdownSelection = app.y1Axis_Secondary_ComparisonTab.Value;
            
            try
                delete(app.y1Plot_Secondary_ComparisonTab);
                app.y1Plot_Secondary_ComparisonTab = app.changeGraphDataSet(...
                    'secondary', ...
                    dropdownSelection, ...
                    app.y1Plot_Secondary_ComparisonTab, ...
                    app.graph_Secondary_ComparisonTab, ...
                    'left','runtime'); 
            catch
                uialert(app.Triaxial_Experiment_Results, 'An error occured while loading a new dataset for the selected graph.', 'warning');
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Progress bar closing
            close(app.loadingProgBar);  
        end

        % Cell edit callback: timelogTableActual
        function timelogTableActualCellEdit2(app, event)
            indices = event.Indices;   %row and column numer of event    
             
            ylimit=app.graph_TimelogTab.YLim; %needed to place number in plot   
            app.eventRow=indices(1);
            
            isChecked=app.timelogTableActual.Data{app.eventRow,indices(2)}; %finds cheked boxes
            
            if isChecked==1
                RuntimeEvent=app.timelog(app.eventRow,3); %find runtime
                RuntimeEvent=table2array(RuntimeEvent);
                %plot vertical line
                app.plotline(app.eventRow)=plot(app.graph_TimelogTab, [RuntimeEvent RuntimeEvent], [ylimit(1,1) ylimit(1,2)], 'm-', 'HandleVisibility', 'off');
                %plot row number
                nums=num2str(app.eventRow); 
                v=ones(size(RuntimeEvent))*(ylimit(1,2)*0.8); %location of number y axis
                app.plottext(app.eventRow)=text(app.graph_TimelogTab, RuntimeEvent,v,nums);
                
            
            elseif isChecked==0
                delete(app.plotline(app.eventRow))
                delete(app.plottext(app.eventRow))
            
            end 
        end

        % Value changed function: y2Axis_Secondary_ComparisonTab
        function y2Axis_Secondary_ComparisonTabValueChanged(app, event)

            dropdownSelection = event.Value;

            try
                delete(app.y2Plot_Secondary_ComparisonTab);
            catch
            end
            
            try
                app.y2Plot_Secondary_ComparisonTab = app.changeGraphDataSet(...
                    'secondary', ...
                    dropdownSelection, ...
                    app.y2Plot_Secondary_ComparisonTab, ...
                    app.graph_Secondary_ComparisonTab, ...
                    'right','runtime');   
            catch
                uialert(app.Triaxial_Experiment_Results, 'An error occured while loading a new dataset for the selected graph.', 'warning');
            end
        end

        % Value changed function: y2Axis_Primary_ComparisonTab
        function y2Axis_Primary_ComparisonTabValueChanged(app, event)
            dropdownSelection = event.Value;
            
            try
                delete(app.y2Plot_Primary_ComparisonTab);
            catch
            end
            
            try
                app.y2Plot_Primary_ComparisonTab = app.changeGraphDataSet(...
                    'primary', ...
                    dropdownSelection, ...
                    app.y2Plot_Primary_ComparisonTab, ...
                    app.graph_Primary_ComparisonTab, ...
                    'right','runtime');
            catch
                uialert(app.Triaxial_Experiment_Results, 'An error occured while loading a new dataset for the selected graph.', 'warning');
            end

        end

        % Button pushed function: ClearEventsButton
        function ClearEventsButtonPushed(app, event)
            cla(app.graph_TimelogTab,'reset') %clear graph
            tf=isempty(app.db_metadata_Primary.timeLog); %check if timelog exists
           
            
            %uncheck select events column
             if tf==0 
                app.timelogTableActual.Data{:,4}=0;
                
             end 
%            try
             app.y1Plot_TimelogTab=app.changeGraphDataSet(...
                    'primary', ...
                    app.y1Axis_TimelogTab.Value, ...
                    app.y1Plot_TimelogTab, ...
                    app.graph_TimelogTab, ...
                    'left','runtime');
              
              app.y2Plot_TimelogTab=app.changeGraphDataSet(...
                    'primary', ...
                    app.y2Axis_TimelogTab.Value, ...
                    app.y2Plot_TimelogTab, ...
                    app.graph_TimelogTab, ...
                    'right','runtime');
                
%             catch
%                 uialert(app.Triaxial_Experiment_Results, 'An error occured while loading a new dataset for the selected graph.', 'warning');
%             end 


        end

        % Value changed function: y1Axis_TimelogTab
        function y1Axis_TimelogTabValueChanged(app, event)
            
            dropdownSelection = event.Value;
            
            try
                delete(app.y1Plot_TimelogTab);
            catch
            end
            
            try
                app.y1Plot_TimelogTab = app.changeGraphDataSet( ...
                    'primary', ...
                    dropdownSelection, ...
                    app.y1Plot_TimelogTab, ...
                    app.graph_TimelogTab, ...
                    'left','runtime');
            catch
                uialert(app.Triaxial_Experiment_Results, 'An error occured while loading a new dataset for the selected graph.', 'warning');
            end
        
        end

        % Value changed function: y1Axis_Primary_ComparisonTab
        function y1Axis_Primary_ComparisonTabValueChanged(app, event)
           
            dropdownSelection = event.Value;
            
            try
                delete(app.y1Plot_Primary_ComparisonTab);
            catch
            end
            
            try
                app.y1Plot_Primary_ComparisonTab = app.changeGraphDataSet(...
                    'primary', ...
                    dropdownSelection, ...
                    app.y1Plot_Primary_ComparisonTab, ...
                    app.graph_Primary_ComparisonTab, ...
                    'left','runtime');   
            catch
                uialert(app.Triaxial_Experiment_Results, 'An error occured while loading a new dataset for the selected graph.', 'warning');
            end
  
        end

        % Value changed function: y1Axis_Secondary_ComparisonTab
        function y1Axis_Secondary_ComparisonTabValueChanged(app, event)
            dropdownSelection = event.Value;
            
            try
                delete(app.y1Plot_Secondary_ComparisonTab);
            catch
            end
            
            try
                app.y1Plot_Secondary_ComparisonTab = app.changeGraphDataSet(...
                    'secondary', ...
                    dropdownSelection, ...
                    app.y1Plot_Secondary_ComparisonTab, ...
                    app.graph_Secondary_ComparisonTab, ...
                    'left','runtime');   
            catch
                uialert(app.Triaxial_Experiment_Results, 'An error occured while loading a new dataset for the selected graph.', 'warning');
            end

        end

        % Value changed function: y1Axis_Primary_ComparisonTab2
        function y1Axis_Primary_ComparisonTab2ValueChanged(app, event)
    dropdownSelection = event.Value;
            
            try
                delete(app.y1Plot_Primary_ComparisonTab2);
            catch
            end
            
%             try
                app.y1Plot_Primary_ComparisonTab2 = app.changeGraphDataSet(...
                    'primary', ...
                    dropdownSelection, ...
                    app.y1Plot_Primary_ComparisonTab2, ...
                    app.graph_Primary_ComparisonTab2, ...
                    'left',app.xAxis_Primary_ComparisonTab2.Value);   
%             catch
%                 uialert(app.Triaxial_Experiment_Results, 'An error occured while loading a new dataset for the selected graph.', 'warning');
%             end
            
        end

        % Value changed function: y2Axis_Primary_ComparisonTab2
        function y2Axis_Primary_ComparisonTab2ValueChanged(app, event)
   dropdownSelection = event.Value;
            
            try
                delete(app.y2Plot_Primary_ComparisonTab2);
            catch
            end
            
            try
                app.y2Plot_Primary_ComparisonTab2 = app.changeGraphDataSet(...
                    'primary', ...
                    dropdownSelection, ...
                    app.y2Plot_Primary_ComparisonTab2, ...
                    app.graph_Primary_ComparisonTab2, ...
                    'right',app.xAxis_Primary_ComparisonTab2.Value);   
            catch
                uialert(app.Triaxial_Experiment_Results, 'An error occured while loading a new dataset for the selected graph.', 'warning');
            end
            
        end

        % Value changed function: y1Axis_Secondary_ComparisonTab2
        function y1Axis_Secondary_ComparisonTab2ValueChanged(app, event)
             dropdownSelection = event.Value;
            
            try
                delete(app.y1Plot_Secondary_ComparisonTab2);
            catch
            end
            
            try
                app.y1Plot_Secondary_ComparisonTab2 = app.changeGraphDataSet(...
                    'secondary', ...
                    dropdownSelection, ...
                    app.y1Plot_Secondary_ComparisonTab2, ...
                    app.graph_secondary_ComparisonTab2, ...
                    'left',app.xAxis_Secondary_ComparisonTab2.Value);   
            catch
                uialert(app.Triaxial_Experiment_Results, 'An error occured while loading a new dataset for the selected graph.', 'warning');
            end
            
        end

        % Value changed function: y2Axis_Secondary_ComparisonTab2
        function y2Axis_Secondary_ComparisonTab2ValueChanged(app, event)
        dropdownSelection = event.Value;

            try
                delete(app.y2Plot_Secondary_ComparisonTab2);
            catch
            end
            
            try
                app.y2Plot_Secondary_ComparisonTab2 = app.changeGraphDataSet(...
                    'secondary', ...
                    dropdownSelection, ...
                    app.y2Plot_Secondary_ComparisonTab2, ...
                    app.graph_secondary_ComparisonTab2, ...
                    'right',app.xAxis_Secondary_ComparisonTab2.Value);   
            catch
                uialert(app.Triaxial_Experiment_Results, 'An error occured while loading a new dataset for the selected graph.', 'warning');
            end 
        end

        % Value changed function: xAxis_Primary_ComparisonTab2
        function xAxis_Primary_ComparisonTab2ValueChanged(app, event)
  
        dropdownSelection = event.Value;

            try
                delete(app.xPlot_Primary_ComparisonTab2);
            catch
            end
            
            try
                app.xPlot_Primary_ComparisonTab2 = app.changeGraphDataSet(...
                    'primary', ...
                    app.y1Axis_Primary_ComparisonTab2.Value, ...
                    app.xPlot_Primary_ComparisonTab2, ...
                    app.graph_Primary_ComparisonTab2, ...
                    'left',dropdownSelection);   
            catch
                uialert(app.Triaxial_Experiment_Results, 'An error occured while loading a new dataset for the selected graph.', 'warning');
            end 
            
      app.y2Axis_Primary_ComparisonTab2.Value='none';      
            
        end

        % Value changed function: xAxis_Secondary_ComparisonTab2
        function xAxis_Secondary_ComparisonTab2ValueChanged(app, event)
           dropdownSelection = event.Value;

            try
                delete(app.xPlot_Secondary_ComparisonTab2);
            catch
            end
            
%             try
                app.xPlot_Secondary_ComparisonTab2 = app.changeGraphDataSet(...
                    'secondary', ...
                    app.y1Axis_Secondary_ComparisonTab2.Value, ...
                    app.xPlot_Secondary_ComparisonTab2, ...
                    app.graph_Secondary_ComparisonTab2, ...
                    'left',dropdownSelection);   
%             catch
%                 uialert(app.Triaxial_Experiment_Results, 'An error occured while loading a new dataset for the selected graph.', 'warning');
%             end 
            
            app.y2Axis_Secondary_ComparisonTab2.Value='none';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Triaxial_Experiment_Results = uifigure('Visible', 'off');
            app.Triaxial_Experiment_Results.Name = 'MERID Datainspector';
            app.TabGroup = uitabgroup(app.Triaxial_Experiment_Results);
            app.experimentsAndRockDataTab = uitab(app.TabGroup);
            app.experimentsAndRockDataTab.Title = 'Experiments and Rock Data';
            app.experimentOverviewPanel = uipanel(app.experimentsAndRockDataTab);
            app.experimentOverviewPanel.Title = 'Experiment Overview';
            app.experimentsOverviewTable = uitable(app.experimentOverviewPanel);
            app.experimentsOverviewTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.experimentsOverviewTable.RowName = {};
            app.experimentsOverviewTable.ColumnSortable = true;
            app.experimentsOverviewTable.ColumnEditable = false;
            app.SelectedExperimentLabel = uilabel(app.experimentOverviewPanel);
            app.SelectedExperimentLabel.Text = 'Selected Experiment:';
            app.selectExperiment_Primary = uidropdown(app.experimentOverviewPanel);
            app.selectExperiment_Primary.Items = {'- no selection -'};
            app.selectExperiment_Primary.ItemsData = {'0'};
            app.selectExperiment_Primary.ValueChangedFcn = createCallbackFcn(app, @selectExperiment_PrimaryValueChanged, true);
            app.selectExperiment_Primary.Value = '0';
            app.rockDataPanel = uipanel(app.experimentsAndRockDataTab);
            app.rockDataPanel.Title = 'Rock Data';
            app.RockDataTable = uitable(app.rockDataPanel);
            app.RockDataTable.ColumnName = {'Value'};
            app.RockDataTable.RowName = {''};
            app.RockDataTable.ColumnSortable = false;
            app.DataAndOutliersTab = uitab(app.TabGroup);
            app.DataAndOutliersTab.Title = 'Data and Outliers';
            app.DataAndWeightPanel = uipanel(app.DataAndOutliersTab);
            app.DataAndWeightPanel.Title = 'All Data and Weight';
            app.graphOverview = uiaxes(app.DataAndWeightPanel);
            title(app.graphOverview, 'Title')
            xlabel(app.graphOverview, 'X')
            ylabel(app.graphOverview, 'Y')
            app.graphWeight = uiaxes(app.DataAndWeightPanel);
            title(app.graphWeight, 'Title')
            xlabel(app.graphWeight, 'X')
            ylabel(app.graphWeight, 'Y')
            app.displayOutliersWeight = uicheckbox(app.DataAndWeightPanel);
            app.displayOutliersWeight.ValueChangedFcn = createCallbackFcn(app, @displayOutliersWeightValueChanged, true);
            app.displayOutliersWeight.Text = 'Display outliers';
            app.correctedGraphWeight = uicheckbox(app.DataAndWeightPanel);
            app.correctedGraphWeight.ValueChangedFcn = createCallbackFcn(app, @correctedGraphWeightValueChanged, true);
            app.correctedGraphWeight.Text = 'Display corrected graph';
            app.FluidFlowAndDeformationPanel = uipanel(app.DataAndOutliersTab);
            app.FluidFlowAndDeformationPanel.Title = 'Fluid Pressure and deformation';
            app.graphFluidFlow = uiaxes(app.FluidFlowAndDeformationPanel);
            title(app.graphFluidFlow, 'Title')
            xlabel(app.graphFluidFlow, 'X')
            ylabel(app.graphFluidFlow, 'Y')
            app.graphDeformation = uiaxes(app.FluidFlowAndDeformationPanel);
            title(app.graphDeformation, 'Title')
            xlabel(app.graphDeformation, 'X')
            ylabel(app.graphDeformation, 'Y')
            app.timelogTab = uitab(app.TabGroup);
            app.timelogTab.Title = 'Results';
            app.permeabilityResultsPanel = uipanel(app.timelogTab);
            app.permeabilityResultsPanel.Title = 'Permeability results';
            app.graph_TimelogTab = uiaxes(app.permeabilityResultsPanel);
            title(app.graph_TimelogTab, 'Title')
            xlabel(app.graph_TimelogTab, {'Runtime (hh:mm)'; ''})
            ylabel(app.graph_TimelogTab, {'Permeability'; ''})
            app.DisplayonrightaxisLabel = uilabel(app.permeabilityResultsPanel);
            app.DisplayonrightaxisLabel.Text = 'Display on right axis:';
            app.y2Axis_TimelogTab = uidropdown(app.permeabilityResultsPanel);
            app.y2Axis_TimelogTab.Items = {'- no selection -'};
            app.y2Axis_TimelogTab.ItemsData = {'0'};
            app.y2Axis_TimelogTab.ValueChangedFcn = createCallbackFcn(app, @y2Axis_TimelogTabValueChanged, true);
            app.y2Axis_TimelogTab.Value = '0';
            app.ClearEventsButton = uibutton(app.permeabilityResultsPanel, 'push');
            app.ClearEventsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearEventsButtonPushed, true);
            app.ClearEventsButton.Text = 'Clear marked events';
            app.timelogPanel = uipanel(app.permeabilityResultsPanel);
            app.timelogPanel.Title = 'Timelog';
            app.timelogTableActual = uitable(app.timelogPanel);
            app.timelogTableActual.ColumnName = {'column 1'};
            app.timelogTableActual.RowName = {};
            app.timelogTableActual.ColumnEditable = [false false false true];
            app.timelogTableActual.CellEditCallback = createCallbackFcn(app, @timelogTableActualCellEdit2, true);
            app.timelogTablePre = uitable(app.timelogPanel);
            app.timelogTablePre.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.timelogTablePre.RowName = {};
            app.timelogTablePost = uitable(app.timelogPanel);
            app.timelogTablePost.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.timelogTablePost.RowName = {};
            app.TimestepminLabel_2 = uilabel(app.permeabilityResultsPanel);
            app.TimestepminLabel_2.Text = 'Timestep (min):';
            app.selectTimestep_Primary = uidropdown(app.permeabilityResultsPanel);
            app.selectTimestep_Primary.Items = {'- no selection -'};
            app.selectTimestep_Primary.ItemsData = {'0'};
            app.selectTimestep_Primary.ValueChangedFcn = createCallbackFcn(app, @selectTimestep_PrimaryValueChanged, true);
            app.selectTimestep_Primary.Value = '0';
            app.DisplayonleftaxisLabel = uilabel(app.permeabilityResultsPanel);
            app.DisplayonleftaxisLabel.Text = 'Display on left axis:';
            app.y1Axis_TimelogTab = uidropdown(app.permeabilityResultsPanel);
            app.y1Axis_TimelogTab.Items = {'- no selection -'};
            app.y1Axis_TimelogTab.ItemsData = {'0'};
            app.y1Axis_TimelogTab.ValueChangedFcn = createCallbackFcn(app, @y1Axis_TimelogTabValueChanged, true);
            app.y1Axis_TimelogTab.Value = '0';
            app.ComparingResultsTab = uitab(app.TabGroup);
            app.ComparingResultsTab.Title = 'Comparing Results';
            app.ComparePanel = uipanel(app.ComparingResultsTab);
            app.graph_Primary_ComparisonTab = uiaxes(app.ComparePanel);
            title(app.graph_Primary_ComparisonTab, 'Title')
            xlabel(app.graph_Primary_ComparisonTab, 'X')
            ylabel(app.graph_Primary_ComparisonTab, 'Y')
            app.graph_Secondary_ComparisonTab = uiaxes(app.ComparePanel);
            title(app.graph_Secondary_ComparisonTab, 'Title')
            xlabel(app.graph_Secondary_ComparisonTab, 'X')
            ylabel(app.graph_Secondary_ComparisonTab, 'Y')
            app.selectExperiment_Secondary = uidropdown(app.ComparePanel);
            app.selectExperiment_Secondary.Items = {'- no selection -'};
            app.selectExperiment_Secondary.ItemsData = {'0'};
            app.selectExperiment_Secondary.ValueChangedFcn = createCallbackFcn(app, @selectExperiment_SecondaryValueChanged, true);
            app.selectExperiment_Secondary.Value = '0';
            app.ComparetoExperimentNoLabel = uilabel(app.ComparePanel);
            app.ComparetoExperimentNoLabel.Text = 'Compare to Experiment No:';
            app.TimestepminLabel = uilabel(app.ComparePanel);
            app.TimestepminLabel.Text = 'Timestep (min):';
            app.selectTimestep_Secondary = uidropdown(app.ComparePanel);
            app.selectTimestep_Secondary.Items = {'- no selection -'};
            app.selectTimestep_Secondary.ItemsData = {'0'};
            app.selectTimestep_Secondary.ValueChangedFcn = createCallbackFcn(app, @selectTimestep_SecondaryValueChanged, true);
            app.selectTimestep_Secondary.Value = '0';
            app.DisplayonrightaxisLabel_2 = uilabel(app.ComparePanel);
            app.DisplayonrightaxisLabel_2.Text = 'Display on right axis';
            app.y2Axis_Primary_ComparisonTab = uidropdown(app.ComparePanel);
            app.y2Axis_Primary_ComparisonTab.Items = {'- no selection -'};
            app.y2Axis_Primary_ComparisonTab.ItemsData = {'0'};
            app.y2Axis_Primary_ComparisonTab.ValueChangedFcn = createCallbackFcn(app, @y2Axis_Primary_ComparisonTabValueChanged, true);
            app.y2Axis_Primary_ComparisonTab.Value = '0';
            app.DisplayonrightaxisLabel_3 = uilabel(app.ComparePanel);
            app.DisplayonrightaxisLabel_3.Text = 'Display on right axis';
            app.y2Axis_Secondary_ComparisonTab = uidropdown(app.ComparePanel);
            app.y2Axis_Secondary_ComparisonTab.Items = {'- no selection -'};
            app.y2Axis_Secondary_ComparisonTab.ItemsData = {'0'};
            app.y2Axis_Secondary_ComparisonTab.ValueChangedFcn = createCallbackFcn(app, @y2Axis_Secondary_ComparisonTabValueChanged, true);
            app.y2Axis_Secondary_ComparisonTab.Value = '0';
            app.DisplayonleftaxisDropDownLabel = uilabel(app.ComparePanel);
            app.DisplayonleftaxisDropDownLabel.Text = 'Display on left axis';
            app.y1Axis_Primary_ComparisonTab = uidropdown(app.ComparePanel);
            app.y1Axis_Primary_ComparisonTab.Items = {'- no selection -'};
            app.y1Axis_Primary_ComparisonTab.ItemsData = {'0'};
            app.y1Axis_Primary_ComparisonTab.ValueChangedFcn = createCallbackFcn(app, @y1Axis_Primary_ComparisonTabValueChanged, true);
            app.y1Axis_Primary_ComparisonTab.Value = '0';
            app.DisplayonleftaxisDropDown_2Label = uilabel(app.ComparePanel);
            app.DisplayonleftaxisDropDown_2Label.Text = 'Display on left axis';
            app.y1Axis_Secondary_ComparisonTab = uidropdown(app.ComparePanel);
            app.y1Axis_Secondary_ComparisonTab.Items = {'- no selection -'};
            app.y1Axis_Secondary_ComparisonTab.ItemsData = {'0'};
            app.y1Axis_Secondary_ComparisonTab.ValueChangedFcn = createCallbackFcn(app, @y1Axis_Secondary_ComparisonTabValueChanged, true);
            app.y1Axis_Secondary_ComparisonTab.Value = '0';
            app.RockData_ComparePage_Primary = uitable(app.ComparePanel);
            app.RockData_ComparePage_Primary.ColumnName = {'Property'; 'Value'};
            app.RockData_ComparePage_Primary.RowName = {};
            app.RockData_ComparePage_Secondary = uitable(app.ComparePanel);
            app.RockData_ComparePage_Secondary.ColumnName = {'Property'; 'Value'};
            app.RockData_ComparePage_Secondary.RowName = {};
            app.Compare2Tab = uitab(app.TabGroup);
            app.Compare2Tab.Title = 'Compare2';
            app.graph_Primary_ComparisonTab2 = uiaxes(app.Compare2Tab);
            title(app.graph_Primary_ComparisonTab2, 'Primary Experiment')
            xlabel(app.graph_Primary_ComparisonTab2, 'X')
            ylabel(app.graph_Primary_ComparisonTab2, 'Y')
            app.graph_secondary_ComparisonTab2 = uiaxes(app.Compare2Tab);
            title(app.graph_secondary_ComparisonTab2, 'Secondary Experiment')
            xlabel(app.graph_secondary_ComparisonTab2, 'X')
            ylabel(app.graph_secondary_ComparisonTab2, 'Y')
            app.LeftAxisDropDownLabel = uilabel(app.Compare2Tab);
            app.LeftAxisDropDownLabel.Text = 'Left Axis';
            app.y1Axis_Primary_ComparisonTab2 = uidropdown(app.Compare2Tab);
            app.y1Axis_Primary_ComparisonTab2.ValueChangedFcn = createCallbackFcn(app, @y1Axis_Primary_ComparisonTab2ValueChanged, true);
            app.xAxisDropDown_2Label = uilabel(app.Compare2Tab);
            app.xAxisDropDown_2Label.Text = 'x-Axis';
            app.xAxis_Primary_ComparisonTab2 = uidropdown(app.Compare2Tab);
            app.xAxis_Primary_ComparisonTab2.ValueChangedFcn = createCallbackFcn(app, @xAxis_Primary_ComparisonTab2ValueChanged, true);
            app.RightAxisDropDownLabel = uilabel(app.Compare2Tab);
            app.RightAxisDropDownLabel.Text = 'Right Axis';
            app.y2Axis_Primary_ComparisonTab2 = uidropdown(app.Compare2Tab);
            app.y2Axis_Primary_ComparisonTab2.ValueChangedFcn = createCallbackFcn(app, @y2Axis_Primary_ComparisonTab2ValueChanged, true);
            app.LeftAxisDropDown_2Label = uilabel(app.Compare2Tab);
            app.LeftAxisDropDown_2Label.Text = 'Left Axis';
            app.y1Axis_Secondary_ComparisonTab2 = uidropdown(app.Compare2Tab);
            app.y1Axis_Secondary_ComparisonTab2.ValueChangedFcn = createCallbackFcn(app, @y1Axis_Secondary_ComparisonTab2ValueChanged, true);
            app.xAxisDropDownLabel = uilabel(app.Compare2Tab);
            app.xAxisDropDownLabel.Text = 'x-Axis';
            app.xAxis_Secondary_ComparisonTab2 = uidropdown(app.Compare2Tab);
            app.xAxis_Secondary_ComparisonTab2.ValueChangedFcn = createCallbackFcn(app, @xAxis_Secondary_ComparisonTab2ValueChanged, true);
            app.RightAxisDropDown_2Label = uilabel(app.Compare2Tab);
            app.RightAxisDropDown_2Label.Text = 'Right Axis';
            app.y2Axis_Secondary_ComparisonTab2 = uidropdown(app.Compare2Tab);
            app.y2Axis_Secondary_ComparisonTab2.ValueChangedFcn = createCallbackFcn(app, @y2Axis_Secondary_ComparisonTab2ValueChanged, true);
            % Show the figure after all components are created
            app.Triaxial_Experiment_Results.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Triaxial_Experiment_Results)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Triaxial_Experiment_Results)
        end
    end
end