classdef subFigureSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        Axis                      matlab.ui.container.Tab
        xAxisPanel                matlab.ui.container.Panel
        BeginEditField_4Label     matlab.ui.control.Label
        xAxisLimBeginValue        matlab.ui.control.NumericEditField
        xAxisStepButtonGroup      matlab.ui.container.ButtonGroup
        xAxisStepCount            matlab.ui.control.ToggleButton
        xAxisStepSize             matlab.ui.control.ToggleButton
        xAxisStepCountValue       matlab.ui.control.NumericEditField
        xAxisStepSizeValue        matlab.ui.control.NumericEditField
        xAxisStepAutomatic        matlab.ui.control.ToggleButton
        xAxisLimBegin             matlab.ui.control.CheckBox
        xAxisLimEnd               matlab.ui.control.CheckBox
        EndLabel                  matlab.ui.control.Label
        xAxisLimEndValue          matlab.ui.control.NumericEditField
        xAxisLabel                matlab.ui.control.Label
        xAxisLimBeginResult       matlab.ui.control.Label
        xAxisLimEndResult         matlab.ui.control.Label
        y1AxisPanel               matlab.ui.container.Panel
        BeginEditField_4Label_2   matlab.ui.control.Label
        y1AxisLimBeginValue       matlab.ui.control.NumericEditField
        y1AxisStepButtonGroup     matlab.ui.container.ButtonGroup
        y1AxisStepCount           matlab.ui.control.ToggleButton
        y1AxisStepSize            matlab.ui.control.ToggleButton
        y1AxisStepCountValue      matlab.ui.control.NumericEditField
        y1AxisStepSizeValue       matlab.ui.control.NumericEditField
        y1AxisStepAutomatic       matlab.ui.control.ToggleButton
        y1AxisLimBegin            matlab.ui.control.CheckBox
        y1AxisLimEnd              matlab.ui.control.CheckBox
        EndLabel_2                matlab.ui.control.Label
        y1AxisLimEndValue         matlab.ui.control.NumericEditField
        y1AxisLabel               matlab.ui.control.Label
        y1AxisLimBeginResult      matlab.ui.control.Label
        y1AxisLimEndResult        matlab.ui.control.Label
        y2AxisPanel               matlab.ui.container.Panel
        BeginEditField_4Label_3   matlab.ui.control.Label
        y2AxisLimBeginValue       matlab.ui.control.NumericEditField
        y2AxisStepButtonGroup     matlab.ui.container.ButtonGroup
        y2AxisStepCount           matlab.ui.control.ToggleButton
        y2AxisStepSize            matlab.ui.control.ToggleButton
        y2AxisStepCountValue      matlab.ui.control.NumericEditField
        y2AxisStepSizeValue       matlab.ui.control.NumericEditField
        y2AxisStepAutomatic       matlab.ui.control.ToggleButton
        y2AxisLimBegin            matlab.ui.control.CheckBox
        y2AxisLimEnd              matlab.ui.control.CheckBox
        EndLabel_3                matlab.ui.control.Label
        y2AxisLimEndValue         matlab.ui.control.NumericEditField
        y2AxisLabel               matlab.ui.control.Label
        y2AxisLimBeginResult      matlab.ui.control.Label
        y2AxisLimEndResult        matlab.ui.control.Label
        ColorsTab                 matlab.ui.container.Tab
        ApplymodificationsButton  matlab.ui.control.Button
    end

    
    properties (Access = private) 
        callingApp   % Main app object
		mainFig
		errorColor = [204/255, 7/255, 30/255];
		errorActive = false;
    end
    
    methods (Access = private)
        
        function results = updateSettingValues(app)
			% Prepare x axis
			app.xAxisLabel.Text = app.mainFig.XLabel.String;
			app.xAxisLimBeginValue.Value = app.mainFig.XLim(1);
			app.xAxisLimEndValue.Value = app.mainFig.XLim(2);			
			app.xAxisStepCountValue.Value = length(app.mainFig.XTick)-1;
			app.xAxisStepSizeValue.Value = app.mainFig.XTick(2) - app.mainFig.XTick(1);
			
			if strcmp(app.mainFig.XAxis(1).LimitsMode, 'manual')
				trueFalseValue = true;
			else
				trueFalseValue = false;
			end
			
			app.xAxisLimBegin.Value = trueFalseValue;
			app.xAxisLimBeginValue.Enable = trueFalseValue;
			app.xAxisLimEnd.Value = trueFalseValue;
			app.xAxisLimEndValue.Enable = trueFalseValue;
			
			if strcmp(app.mainFig.XAxis(1).TickValuesMode, 'manual')
				app.xAxisStepCount.Value = 1;
				app.xAxisStepCountValue.Enable = true;
			else
				app.xAxisStepAutomatic.Value = 1;
			end
			
			% Prepare y1 axis	
			app.y1AxisLabel.Text = app.mainFig.YAxis(1).Label.String;
			app.y1AxisLimBeginValue.Value = app.mainFig.YAxis(1).Limits(1);
			app.y1AxisLimEndValue.Value =  app.mainFig.YAxis(1).Limits(2);
			app.y1AxisStepCountValue.Value = length(app.mainFig.YAxis(1).TickValues)-1;
			app.y1AxisStepSizeValue.Value = app.mainFig.YAxis(1).TickValues(2) - app.mainFig.YAxis(1).TickValues(1);
			
			if strcmp(app.mainFig.YAxis(1).LimitsMode, 'manual')
				trueFalseValue = true;
			else
				trueFalseValue = false;
			end
			
			app.y1AxisLimBegin.Value = trueFalseValue;
			app.y1AxisLimBeginValue.Enable = trueFalseValue;
			app.y1AxisLimEnd.Value = trueFalseValue;
			app.y1AxisLimEndValue.Enable = trueFalseValue;
			
			if strcmp(app.mainFig.YAxis(1).TickValuesMode, 'manual')
				app.y1AxisStepCount.Value = 1;
				app.y1AxisStepCountValue.Enable = true;
			else
				app.y1AxisStepAutomatic.Value = 1;
			end
			
			% Prepare y2 axis if exists
			if length(app.mainFig.YAxis) > 1
				if ~strcmp(app.mainFig.YAxis(2).Label.String, '')
					app.y2AxisPanel.Visible = true;
					app.y2AxisLabel.Text = app.mainFig.YAxis(2).Label.String;
					app.y2AxisLimBeginValue.Value = app.mainFig.YAxis(2).Limits(1);
					app.y2AxisLimEndValue.Value =  app.mainFig.YAxis(2).Limits(2);
					app.y2AxisStepCountValue.Value = length(app.mainFig.YAxis(2).TickValues)-1;
					app.y2AxisStepSizeValue.Value = app.mainFig.YAxis(2).TickValues(2) - app.mainFig.YAxis(2).TickValues(1);
					
					if strcmp(app.mainFig.YAxis(2).LimitsMode, 'manual')
						trueFalseValue = true;
					else
						trueFalseValue = false;
					end
					
					app.y2AxisLimBegin.Value = trueFalseValue;
					app.y2AxisLimBeginValue.Enable = trueFalseValue;
					app.y2AxisLimEnd.Value = trueFalseValue;
					app.y2AxisLimEndValue.Enable = trueFalseValue;
					
					if strcmp(app.mainFig.YAxis(2).TickValuesMode, 'manual')
						app.y2AxisStepCount.Value = 1;
						app.y2AxisStepCountValue.Enable = true;
					else
						app.y2AxisStepAutomatic.Value = 1;
					end
					
				else
					app.y2AxisPanel.Visible = false;
				end
			end
			
			results = true;
        end
        
        
        function results = modifyFigure(app)		
		
			% set x axis
			if app.xAxisLimBegin.Value || app.xAxisLimEnd.Value
				app.mainFig.XAxis(1).Limits = [app.xAxisLimBeginValue.Value app.xAxisLimEndValue.Value];
			else
				app.mainFig.XAxis(1).LimitsMode = 'auto';
			end
			
			
			if strcmp(app.xAxisStepButtonGroup.SelectedObject.Text, 'Automatic')
				app.mainFig.XAxis(1).TickValuesMode = 'auto';
			
			elseif strcmp(app.xAxisStepButtonGroup.SelectedObject.Text, 'Count')
				
				range = app.xAxisLimEndValue.Value - app.xAxisLimBeginValue.Value;
				TickValues = [app.xAxisLimBeginValue.Value];
				
				counter = app.xAxisLimBeginValue.Value;
				
				while counter < app.xAxisLimEndValue.Value
					counter = counter + range / (app.xAxisStepCountValue.Value) ;
					TickValues = horzcat(TickValues, counter);					
				end
				
				app.mainFig.XAxis(1).TickValues = TickValues;
				
			
			elseif strcmp(app.xAxisStepButtonGroup.SelectedObject.Text, 'Size')
				counter = app.xAxisLimBeginValue.Value;
				TickValues = [app.xAxisLimBeginValue.Value];
				
				while counter < app.xAxisLimEndValue.Value
					counter = counter + app.xAxisLimBeginValue.Value;
					TickValues = horzcat(TickValues, counter);					
				end
				
				app.mainFig.XAxis(1).TickValues = TickValues;
			end
			
			
			% set y1 axis
			
			app.mainFig.YAxis(1).Limits = [app.y1AxisLimBeginValue.Value app.y1AxisLimEndValue.Value];
			
			if app.y1AxisLimBegin.Value || app.y1AxisLimEnd.Value
				app.mainFig.YAxis(1).Limits = [app.y1AxisLimBeginValue.Value app.y1AxisLimEndValue.Value];
			else
				app.mainFig.YAxis(1).LimitsMode = 'auto';
			end
			
			
			if strcmp(app.y1AxisStepButtonGroup.SelectedObject.Text, 'Automatic')
				app.mainFig.YAxis(1).TickValuesMode = 'auto';
			
			elseif strcmp(app.y1AxisStepButtonGroup.SelectedObject.Text, 'Count')
				
				range = app.y1AxisLimEndValue.Value - app.y1AxisLimBeginValue.Value;
				TickValues = [app.y1AxisLimBeginValue.Value];
				
				counter = app.y1AxisLimBeginValue.Value;
				
				while counter < app.y1AxisLimEndValue.Value
					counter = counter + range / (app.y1AxisStepCountValue.Value) ;
					TickValues = horzcat(TickValues, counter);					
				end
				
				app.mainFig.YAxis(1).TickValues = TickValues;
				
			
			elseif strcmp(app.y1AxisStepButtonGroup.SelectedObject.Text, 'Size')
				counter = app.y1AxisLimBeginValue.Value;
				TickValues = [app.y1AxisLimBeginValue.Value];
				
				while counter < app.y1AxisLimEndValue.Value
					counter = counter + app.y1AxisLimBeginValue.Value;
					TickValues = horzcat(TickValues, counter);					
				end
				
				app.mainFig.YAxis(1).TickValues = TickValues;
			end


			if length(app.mainFig.YAxis) > 1
				if ~strcmp(app.mainFig.YAxis(2).Label.String, '')
					app.mainFig.YAxis(2).Limits = [app.y2AxisLimBeginValue.Value app.y2AxisLimEndValue.Value];
			
					if app.y2AxisLimBegin.Value || app.y2AxisLimEnd.Value
						app.mainFig.YAxis(2).Limits = [app.y2AxisLimBeginValue.Value app.y2AxisLimEndValue.Value];
					else
						app.mainFig.YAxis(2).LimitsMode = 'auto';
					end
					
					
					if strcmp(app.y2AxisStepButtonGroup.SelectedObject.Text, 'Automatic')
						app.mainFig.YAxis(2).TickValuesMode = 'auto';
					
					elseif strcmp(app.y2AxisStepButtonGroup.SelectedObject.Text, 'Count')
						
						range = app.y2AxisLimEndValue.Value - app.y2AxisLimBeginValue.Value;
						TickValues = [app.y2AxisLimBeginValue.Value];
						
						counter = app.y2AxisLimBeginValue.Value;
						
						while counter < app.y2AxisLimEndValue.Value
							counter = counter + range / (app.y2AxisStepCountValue.Value) ;
							TickValues = horzcat(TickValues, counter);					
						end
						
						app.mainFig.YAxis(2).TickValues = TickValues;
						
					
					elseif strcmp(app.y2AxisStepButtonGroup.SelectedObject.Text, 'Size')
						counter = app.y2AxisLimBeginValue.Value;
						TickValues = [app.y2AxisLimBeginValue.Value];
						
						while counter < app.y2AxisLimEndValue.Value
							counter = counter + app.y2AxisLimBeginValue.Value;
							TickValues = horzcat(TickValues, counter);					
						end
						
						app.mainFig.YAxis(2).TickValues = TickValues;
					end
				end
			end
			
			results = true;
			
		end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, fig)
			app.UIFigure.Name = 'Set Experiment Filter';
			app.mainFig = fig;
			
			% Store main app in property for CloseRequestFcn to use
            app.callingApp = mainApp;			
			app.updateSettingValues;
        end

        % Close request function: UIFigure
        function CloseRequest(app, event)
            % Check if any error is active
			try
				if app.errorActive
					msgbox('Error is active');
					return;
				else
					app.modifyFigure;
				end
			catch E
				msgbox('Error while modifying figure')
			end
			
			app.callingApp.FigureSettingsButton.Value = 0;
			delete(app)
			
        end

        % Value changed function: xAxisLimBegin
        function xAxisLimBeginChanged(app, event)
            value = app.xAxisLimBegin.Value;
            
			if value
					% Button is down: show filter dialog
					app.xAxisLimBeginValue.Enable = true;
					
				else
					% Button is up: close and delete filter dialog
					app.xAxisLimBeginValue.Enable = false;
					app.xAxisLimBeginValue.Value = app.mainFig.XLim(1);
					app.xAxisLimBeginValue.BackgroundColor = 'white';
					app.errorActive = false;
			end
			
        end

        % Value changed function: xAxisLimEnd
        function xAxisLimEndChanged(app, event)
            value = app.xAxisLimEnd.Value;
            
			if value
					% Button is down: show filter dialog
					app.xAxisLimEndValue.Enable = true;
					
				else
					% Button is up: close and delete filter dialog
					app.xAxisLimEndValue.Enable = false;
					app.xAxisLimEndValue.Value = app.mainFig.XLim(2);
					app.xAxisLimEndValue.BackgroundColor = 'white';
					app.errorActive = false;
			end
        end

        % Value changed function: xAxisLimBeginValue
        function xAxisLimBeginValueChanged(app, event)
			% Check if new value is valid
			if app.xAxisLimBeginValue.Value >= app.xAxisLimEndValue.Value
				app.xAxisLimBeginValue.BackgroundColor = app.errorColor;
				app.errorActive = true;
			else
				app.xAxisLimBeginValue.BackgroundColor = 'white';
				app.errorActive = false;
			end
			
        end

        % Selection changed function: xAxisStepButtonGroup
        function xAxisStepButtonGroupSelectionChanged(app, event)
            selectedButton = app.xAxisStepButtonGroup.SelectedObject;
			
			if strcmp(selectedButton.Text, 'Count')
				app.xAxisStepCountValue.Enable = true;
				
				app.xAxisStepSizeValue.Enable = false;
				app.xAxisStepSizeValue.Value = app.mainFig.XTick(2) - app.mainFig.XTick(1);
				
			elseif strcmp(selectedButton.Text, 'Size')
				app.xAxisStepSizeValue.Enable = true;
				
				app.xAxisStepCountValue.Enable = false;
				app.xAxisStepCountValue.Value = length(app.mainFig.XTick)-1;
			end


        end

        % Value changed function: xAxisLimEndValue
        function xAxisLimEndValueChanged(app, event)
            % Check if new value is valid
			if app.xAxisLimEndValue.Value <= app.xAxisLimBeginValue.Value
				app.xAxisLimEndValue.BackgroundColor = app.errorColor;
				app.errorActive = true;
			else
				app.xAxisLimEndValue.BackgroundColor = 'white';
				app.errorActive = false;
			end
            
        end

        % Selection changed function: y1AxisStepButtonGroup
        function y1AxisStepButtonGroupSelectionChanged(app, event)
            selectedButton = app.y1AxisStepButtonGroup.SelectedObject;
			
			if strcmp(selectedButton.Text, 'Count')
				app.y1AxisStepCountValue.Enable = true;
				
				app.y1AxisStepSizeValue.Enable = false;
				app.y1AxisStepSizeValue.Value = app.mainFig.XTick(2) - app.mainFig.XTick(1);
				
			elseif strcmp(selectedButton.Text, 'Size')
				app.y1AxisStepSizeValue.Enable = true;
				
				app.y1AxisStepCountValue.Enable = false;
				app.y1AxisStepCountValue.Value = length(app.mainFig.XTick)-1;
			end
            
        end

        % Value changed function: y1AxisLimBegin
        function y1AxisLimBeginChanged(app, event)
            value = app.y1AxisLimBegin.Value;
            
			if value
					% Button is down: show filter dialog
					app.y1AxisLimBeginValue.Enable = true;
					
				else
					% Button is up: close and delete filter dialog
					app.y1AxisLimBeginValue.Enable = false;
					app.y1AxisLimBeginValue.Value = app.mainFig.YAxis(1).Limits(1);
					app.y1AxisLimBeginValue.BackgroundColor = 'white';
					app.errorActive = false;
			end
        end

        % Value changed function: y1AxisLimEnd
        function y1AxisLimEndChanged(app, event)
            value = app.y1AxisLimEnd.Value;
            
			if value
					% Button is down: show filter dialog
					app.y1AxisLimEndValue.Enable = true;
					
				else
					% Button is up: close and delete filter dialog
					app.y1AxisLimEndValue.Enable = false;
					app.y1AxisLimEndValue.Value = app.mainFig.YAxis(1).Limits(2);
					app.y1AxisLimEndValue.BackgroundColor = 'white';
					app.errorActive = false;
			end
			
        end

        % Value changed function: y2AxisLimBegin
        function y2AxisLimBeginChanged(app, event)
            value = app.y2AxisLimBegin.Value;
            
			if value
					% Button is down: show filter dialog
					app.y2AxisLimBeginValue.Enable = true;
					
				else
					% Button is up: close and delete filter dialog
					app.y2AxisLimBeginValue.Enable = false;
					app.y2AxisLimBeginValue.Value = app.mainFig.YAxis(2).Limits(1);
					app.y2AxisLimBeginValue.BackgroundColor = 'white';
					app.errorActive = false;
			end
			
        end

        % Value changed function: y2AxisLimEnd
        function y2AxisLimEndChanged(app, event)
            value = app.y2AxisLimEnd.Value;
			
			if value
					% Button is down: show filter dialog
					app.y2AxisLimEndValue.Enable = true;
					
				else
					% Button is up: close and delete filter dialog
					app.y2AxisLimEndValue.Enable = false;
					app.y2AxisLimEndValue.Value = app.mainFig.YAxis(2).Limits(2);
					app.y2AxisLimEndValue.BackgroundColor = 'white';
					app.errorActive = false;
			end
            
        end

        % Value changed function: y1AxisLimBeginValue
        function y1AxisLimBeginValueChanged(app, event)
            % Check if new value is valid
			if app.y1AxisLimBeginValue.Value >= app.y1AxisLimEndValue.Value
				app.y1AxisLimBeginValue.BackgroundColor = app.errorColor;
				app.errorActive = true;
			else
				app.y1AxisLimBeginValue.BackgroundColor = 'white';
				app.errorActive = false;
			end
			
        end

        % Value changed function: y1AxisLimEndValue
        function y1AxisLimEndValueChanged(app, event)
            % Check if new value is valid
			if app.y1AxisLimEndValue.Value <= app.y1AxisLimBeginValue.Value
				app.y1AxisLimEndValue.BackgroundColor = app.errorColor;
				app.errorActive = true;
			else
				app.y1AxisLimEndValue.BackgroundColor = 'white';
				app.errorActive = false;
			end
            
        end

        % Value changed function: y2AxisLimBeginValue
        function y2AxisLimBeginValueChanged(app, event)
			% Check if new value is valid
			if app.y2AxisLimBeginValue.Value >= app.y2AxisLimEndValue.Value
				app.y2AxisLimBeginValue.BackgroundColor = app.errorColor;
				app.errorActive = true;
			else
				app.y2AxisLimBeginValue.BackgroundColor = 'white';
				app.errorActive = false;
			end
            
        end

        % Value changed function: y2AxisLimEndValue
        function y2AxisLimEndValueChanged(app, event)
			% Check if new value is valid
			if app.y2AxisLimEndValue.Value <= app.y2AxisLimBeginValue.Value
				app.y2AxisLimEndValue.BackgroundColor = app.errorColor;
				app.errorActive = true;
			else
				app.y2AxisLimEndValue.BackgroundColor = 'white';
				app.errorActive = false;
			end
            
        end

        % Button pushed function: ApplymodificationsButton
        function ApplymodificationsButtonPushed(app, event)
            modifyFigure(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Axis = uitab(app.TabGroup);
            app.Axis.Title = 'Axis';
            app.xAxisPanel = uipanel(app.Axis);
            app.xAxisPanel.Title = 'x-Axis';
            app.BeginEditField_4Label = uilabel(app.xAxisPanel);
            app.BeginEditField_4Label.Text = 'Begin';
            app.xAxisLimBeginValue = uieditfield(app.xAxisPanel, 'numeric');
            app.xAxisLimBeginValue.ValueChangedFcn = createCallbackFcn(app, @xAxisLimBeginValueChanged, true);
            app.xAxisLimBeginValue.Enable = 'off';
            app.xAxisStepButtonGroup = uibuttongroup(app.xAxisPanel);
            app.xAxisStepButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @xAxisStepButtonGroupSelectionChanged, true);
            app.xAxisStepButtonGroup.Title = 'Step';
            app.xAxisStepCount = uitogglebutton(app.xAxisStepButtonGroup);
            app.xAxisStepCount.Text = 'Count';
            app.xAxisStepSize = uitogglebutton(app.xAxisStepButtonGroup);
            app.xAxisStepSize.Text = 'Size';
            app.xAxisStepCountValue = uieditfield(app.xAxisStepButtonGroup, 'numeric');
            app.xAxisStepCountValue.Limits = [1 Inf];
            app.xAxisStepCountValue.ValueDisplayFormat = '%.0f';
            app.xAxisStepCountValue.Enable = 'off';
            app.xAxisStepCountValue.Value = 1;
            app.xAxisStepSizeValue = uieditfield(app.xAxisStepButtonGroup, 'numeric');
            app.xAxisStepSizeValue.Limits = [0 Inf];
            app.xAxisStepSizeValue.Enable = 'off';
            app.xAxisStepAutomatic = uitogglebutton(app.xAxisStepButtonGroup);
            app.xAxisStepAutomatic.Text = 'Automatic';
            app.xAxisStepAutomatic.Value = true;
            app.xAxisLimBegin = uicheckbox(app.xAxisPanel);
            app.xAxisLimBegin.ValueChangedFcn = createCallbackFcn(app, @xAxisLimBeginChanged, true);
            app.xAxisLimBegin.Text = '';
            app.xAxisLimEnd = uicheckbox(app.xAxisPanel);
            app.xAxisLimEnd.ValueChangedFcn = createCallbackFcn(app, @xAxisLimEndChanged, true);
            app.xAxisLimEnd.Text = '';
            app.EndLabel = uilabel(app.xAxisPanel);
            app.EndLabel.Text = 'End';
            app.xAxisLimEndValue = uieditfield(app.xAxisPanel, 'numeric');
            app.xAxisLimEndValue.ValueChangedFcn = createCallbackFcn(app, @xAxisLimEndValueChanged, true);
            app.xAxisLimEndValue.Enable = 'off';
            app.xAxisLabel = uilabel(app.xAxisPanel);
            app.xAxisLimBeginResult = uilabel(app.xAxisPanel);
            app.xAxisLimBeginResult.Text = {'Result'; ''};
            app.xAxisLimEndResult = uilabel(app.xAxisPanel);
            app.xAxisLimEndResult.Text = {'Result'; ''};
            app.y1AxisPanel = uipanel(app.Axis);
            app.y1AxisPanel.Title = 'y1-Axis (left)';
            app.BeginEditField_4Label_2 = uilabel(app.y1AxisPanel);
            app.BeginEditField_4Label_2.Text = 'Begin';
            app.y1AxisLimBeginValue = uieditfield(app.y1AxisPanel, 'numeric');
            app.y1AxisLimBeginValue.ValueChangedFcn = createCallbackFcn(app, @y1AxisLimBeginValueChanged, true);
            app.y1AxisLimBeginValue.Enable = 'off';
            app.y1AxisStepButtonGroup = uibuttongroup(app.y1AxisPanel);
            app.y1AxisStepButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @y1AxisStepButtonGroupSelectionChanged, true);
            app.y1AxisStepButtonGroup.Title = 'Step';
            app.y1AxisStepCount = uitogglebutton(app.y1AxisStepButtonGroup);
            app.y1AxisStepCount.Text = 'Count';
            app.y1AxisStepSize = uitogglebutton(app.y1AxisStepButtonGroup);
            app.y1AxisStepSize.Text = 'Size';
            app.y1AxisStepCountValue = uieditfield(app.y1AxisStepButtonGroup, 'numeric');
            app.y1AxisStepCountValue.Limits = [1 Inf];
            app.y1AxisStepCountValue.ValueDisplayFormat = '%.0f';
            app.y1AxisStepCountValue.Enable = 'off';
            app.y1AxisStepCountValue.Value = 1;
            app.y1AxisStepSizeValue = uieditfield(app.y1AxisStepButtonGroup, 'numeric');
            app.y1AxisStepSizeValue.Limits = [0 Inf];
            app.y1AxisStepSizeValue.ValueDisplayFormat = '%11.2g';
            app.y1AxisStepSizeValue.Enable = 'off';
            app.y1AxisStepAutomatic = uitogglebutton(app.y1AxisStepButtonGroup);
            app.y1AxisStepAutomatic.Text = 'Automatic';
            app.y1AxisStepAutomatic.Value = true;
            app.y1AxisLimBegin = uicheckbox(app.y1AxisPanel);
            app.y1AxisLimBegin.ValueChangedFcn = createCallbackFcn(app, @y1AxisLimBeginChanged, true);
            app.y1AxisLimBegin.Text = '';
            app.y1AxisLimEnd = uicheckbox(app.y1AxisPanel);
            app.y1AxisLimEnd.ValueChangedFcn = createCallbackFcn(app, @y1AxisLimEndChanged, true);
            app.y1AxisLimEnd.Text = '';
            app.EndLabel_2 = uilabel(app.y1AxisPanel);
            app.EndLabel_2.Text = 'End';
            app.y1AxisLimEndValue = uieditfield(app.y1AxisPanel, 'numeric');
            app.y1AxisLimEndValue.ValueChangedFcn = createCallbackFcn(app, @y1AxisLimEndValueChanged, true);
            app.y1AxisLimEndValue.Enable = 'off';
            app.y1AxisLabel = uilabel(app.y1AxisPanel);
            app.y1AxisLimBeginResult = uilabel(app.y1AxisPanel);
            app.y1AxisLimBeginResult.Text = {'Result'; ''};
            app.y1AxisLimEndResult = uilabel(app.y1AxisPanel);
            app.y1AxisLimEndResult.Text = {'Result'; ''};
            app.y2AxisPanel = uipanel(app.Axis);
            app.y2AxisPanel.Title = 'y2-Axis (right)';
            app.BeginEditField_4Label_3 = uilabel(app.y2AxisPanel);
            app.BeginEditField_4Label_3.Text = 'Begin';
            app.y2AxisLimBeginValue = uieditfield(app.y2AxisPanel, 'numeric');
            app.y2AxisLimBeginValue.ValueChangedFcn = createCallbackFcn(app, @y2AxisLimBeginValueChanged, true);
            app.y2AxisLimBeginValue.Enable = 'off';
            app.y2AxisStepButtonGroup = uibuttongroup(app.y2AxisPanel);
            app.y2AxisStepButtonGroup.Title = 'Step';
            app.y2AxisStepCount = uitogglebutton(app.y2AxisStepButtonGroup);
            app.y2AxisStepCount.Text = 'Count';
            app.y2AxisStepSize = uitogglebutton(app.y2AxisStepButtonGroup);
            app.y2AxisStepSize.Text = 'Size';
            app.y2AxisStepCountValue = uieditfield(app.y2AxisStepButtonGroup, 'numeric');
            app.y2AxisStepCountValue.Limits = [1 Inf];
            app.y2AxisStepCountValue.ValueDisplayFormat = '%.0f';
            app.y2AxisStepCountValue.Enable = 'off';
            app.y2AxisStepCountValue.Value = 1;
            app.y2AxisStepSizeValue = uieditfield(app.y2AxisStepButtonGroup, 'numeric');
            app.y2AxisStepSizeValue.Limits = [0 Inf];
            app.y2AxisStepSizeValue.ValueDisplayFormat = '%11.2g';
            app.y2AxisStepSizeValue.Enable = 'off';
            app.y2AxisStepAutomatic = uitogglebutton(app.y2AxisStepButtonGroup);
            app.y2AxisStepAutomatic.Text = 'Automatic';
            app.y2AxisStepAutomatic.Value = true;
            app.y2AxisLimBegin = uicheckbox(app.y2AxisPanel);
            app.y2AxisLimBegin.ValueChangedFcn = createCallbackFcn(app, @y2AxisLimBeginChanged, true);
            app.y2AxisLimBegin.Text = '';
            app.y2AxisLimEnd = uicheckbox(app.y2AxisPanel);
            app.y2AxisLimEnd.ValueChangedFcn = createCallbackFcn(app, @y2AxisLimEndChanged, true);
            app.y2AxisLimEnd.Text = '';
            app.EndLabel_3 = uilabel(app.y2AxisPanel);
            app.EndLabel_3.Text = 'End';
            app.y2AxisLimEndValue = uieditfield(app.y2AxisPanel, 'numeric');
            app.y2AxisLimEndValue.ValueChangedFcn = createCallbackFcn(app, @y2AxisLimEndValueChanged, true);
            app.y2AxisLimEndValue.Enable = 'off';
            app.y2AxisLabel = uilabel(app.y2AxisPanel);
            app.y2AxisLimBeginResult = uilabel(app.y2AxisPanel);
            app.y2AxisLimBeginResult.Text = {'Result'; ''};
            app.y2AxisLimEndResult = uilabel(app.y2AxisPanel);
            app.y2AxisLimEndResult.Text = {'Result'; ''};
            app.ColorsTab = uitab(app.TabGroup);
            app.ColorsTab.Title = 'Colors';
            app.ApplymodificationsButton = uibutton(app.UIFigure, 'push');
            app.ApplymodificationsButton.ButtonPushedFcn = createCallbackFcn(app, @ApplymodificationsButtonPushed, true);
            app.ApplymodificationsButton.Text = 'Apply modifications';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = subFigureSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end